classdef Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Tree                        matlab.ui.container.Tree
        WithinSubjectsFactorsNode   matlab.ui.container.TreeNode
        BetweenSubjectsFactorsNode  matlab.ui.container.TreeNode
        MeasuresNode                matlab.ui.container.TreeNode
        TabGroup                    matlab.ui.container.TabGroup
        FactorTab                   matlab.ui.container.Tab
        Tree2                       matlab.ui.container.Tree
        LevelsLabel                 matlab.ui.control.Label
        DeleteFactorButton          matlab.ui.control.Button
        AddLevelButton              matlab.ui.control.Button
        RemoveLevelButton           matlab.ui.control.Button
        MeasureTab                  matlab.ui.container.Tab
        ButtonGroup                 matlab.ui.container.ButtonGroup
        ScaleButton                 matlab.ui.control.ToggleButton
        OrdinalButton               matlab.ui.control.ToggleButton
        NominalButton               matlab.ui.control.ToggleButton
        MeasureTypeLabel            matlab.ui.control.Label
        DeleteMeasureButton         matlab.ui.control.Button
        Tab                         matlab.ui.container.Tab
        Panel                       matlab.ui.container.Panel
        WithinSubjectsButton        matlab.ui.control.Button
        BetweenSubjectsButton       matlab.ui.control.Button
        AddFactorButton             matlab.ui.control.Button
        AddMeasureButton            matlab.ui.control.Button
        DoneButton                  matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
    end

    
    methods (Access = public)
        function loadobj(app, tree, des)
            % load BS factors
            i = strcmp({tree.NodeData}, 'B');
            par = tree(i);
            for n = 1:length(des.BS_Factors)
                uitreenode(par,  ...
                    'Text', des.BS_Factors(n).Name, ...
                    'NodeData', des.BS_Factors(n).Levels, ...
                    'Tag', 'B' ...
                    );
            end
            
            % load WS Factors
            i = strcmp({tree.NodeData}, 'W');
            par = tree(i);
            for n = 1:length(des.WS_Factors)
                uitreenode(par,  ...
                    'Text', des.WS_Factors(n).Name, ...
                    'NodeData', des.WS_Factors(n).Levels, ...
                    'Tag', 'W' ...
                    );
            end
            
            % load Measures
            i = strcmp({tree.NodeData}, 'M');
            par = tree(i);
            for n = 1:length(des.Measures)
                uitreenode(par,  ...
                    'Text', des.Measures(n).Name, ...
                    'NodeData', des.Measures(n).Type, ...
                    'Tag', 'M' ...
                    );
            end
        end
        
        function saveobj(app)
            M = app.Tree.Children(strcmp({app.Tree.Children.NodeData}, {'M'})).Children;
            B = app.Tree.Children(strcmp({app.Tree.Children.NodeData}, {'B'})).Children;
            W = app.Tree.Children(strcmp({app.Tree.Children.NodeData}, {'W'})).Children;
            
            des = struct('BS_Factors', struct('Name', {B.Text}', 'Levels', {B.NodeData}'), ...
                'WS_Factors', struct('Name', {W.Text}', 'Levels', {W.NodeData}'), ...
                'Measures', struct('Name', {M.Text}', 'Type', {M.NodeData}') ...
                );
            save('Design\Design.mat', 'des')
        end
    end
    

    methods (Access = private)

        % Button pushed function: WithinSubjectsButton
        function AddWSFactor(app, event)
            i = strcmp({app.Tree.Children.NodeData}, 'W');
            par = app.Tree.Children(i);
            uitreenode(par,  ...
                    'Text', 'Factor Name...', ...
                    'NodeData', struct('Text', {'Level 1 name...'; 'Level 2 name...'}, 'Tag', {'L'; 'L'}), ...
                    'Tag', 'W' ...
                    );
                app.Panel.Visible = false;
        end

        % Button pushed function: AddFactorButton
        function AddFactorButtonPushed(app, event)
            app.Panel.Visible = true;
        end

        % Button pushed function: BetweenSubjectsButton
        function BetweenSubjectsButtonPushed(app, event)
            i = strcmp({app.Tree.Children.NodeData}, 'B');
            par = app.Tree.Children(i);
            uitreenode(par,  ...
                    'Text', 'Factor Name...', ...
                    'NodeData', struct('Text', {'Level 1 name...'; 'Level 2 name...'}, 'Tag', {'L'; 'L'}), ...
                    'Tag', 'B' ...
                    );
                app.Panel.Visible = false;
        end

        % Button pushed function: AddMeasureButton
        function AddMeasureButtonPushed(app, event)
            i = strcmp({app.Tree.Children.NodeData}, 'M');
            par = app.Tree.Children(i);
            uitreenode(par,  ...
                    'Text', 'Measure Name...', ...
                    'NodeData', struct('Scale', true, 'Ordinal', false, 'Nominal', false), ...
                    'Tag', 'M' ...
                    );
                app.Panel.Visible = false;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            if ...
                    isequal(event.PreviousSelectedNodes, app.WithinSubjectsFactorsNode) || ...
                    isequal(event.PreviousSelectedNodes, app.BetweenSubjectsFactorsNode) || ....
                    isequal(event.PreviousSelectedNodes, app.MeasuresNode) || ...
                    isempty(event.PreviousSelectedNodes)
            else
                switch event.PreviousSelectedNodes.Tag %save previous selection
                    case {'W', 'B'}
                        try
                            event.PreviousSelectedNodes.NodeData = struct('Text', {app.Tree2.Children.Text}', 'Tag', {app.Tree2.Children.Tag}');
                        catch
                            event.PreviousSelectedNodes.NodeData = struct('Text', {}, 'Tag', {});
                        end
                        delete(app.Tree2.Children)
                    case 'M'
                        event.PreviousSelectedNodes.NodeData = struct('Scale', app.ScaleButton.Value, 'Ordinal', app.OrdinalButton.Value, 'Nominal', app.NominalButton.Value);
                end
            end
            
            if ...
                    isequal(event.SelectedNodes, app.WithinSubjectsFactorsNode) || ...
                    isequal(event.SelectedNodes, app.BetweenSubjectsFactorsNode) || ....
                    isequal(event.SelectedNodes, app.MeasuresNode) || ...
                    isempty(event.SelectedNodes)
                app.TabGroup.SelectedTab = app.Tab;
            else
                switch event.SelectedNodes.Tag %load new selection
                    case {'W', 'B'}
                        app.TabGroup.SelectedTab = app.FactorTab;
                        delete(app.Tree2.Children)
                        for n = 1:length(event.SelectedNodes.NodeData)
                            uitreenode(app.Tree2,  ...
                                'Text', event.SelectedNodes.NodeData(n).Text, ...
                                'Tag', event.SelectedNodes.NodeData(n).Tag ...
                                );
                        end
                        app.RemoveLevelButton.Enable = length(app.Tree2.Children) > 2;
                    case 'M'
                        app.TabGroup.SelectedTab = app.MeasureTab;
                        app.ScaleButton.Value = event.SelectedNodes.NodeData.Scale;
                        app.OrdinalButton.Value = event.SelectedNodes.NodeData.Ordinal;
                        app.NominalButton.Value = event.SelectedNodes.NodeData.Nominal;
                end
            end


        end

        % Button pushed function: AddLevelButton
        function AddLevelButtonPushed(app, event)
            newNode = uitreenode(app.Tree2,  ...
                'Text', 'Level Name...', ...
                'Tag', 'L' ...
                );
            app.Tree.SelectedNodes.NodeData = app.Tree2.Children;
            app.RemoveLevelButton.Enable = length(app.Tree2.Children) > 2;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.TabGroup.SelectedTab = event.OldValue; %prevent changing tabs manually
        end

        % Button pushed function: RemoveLevelButton
        function RemoveLevelButtonPushed(app, event)
            delete(app.Tree2.SelectedNodes)
            app.RemoveLevelButton.Enable = length(app.Tree2.Children) > 2;
        end

        % Button pushed function: DeleteFactorButton, 
        % DeleteMeasureButton
        function DeleteFactorOrMeasure(app, event)
            delete(app.Tree.SelectedNodes)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            saveobj(app)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Editable = 'on';
            app.WithinSubjectsFactorsNode = uitreenode(app.Tree);
            app.WithinSubjectsFactorsNode.NodeData = 'W';
            app.WithinSubjectsFactorsNode.Text = 'Within-Subjects Factors';
            app.BetweenSubjectsFactorsNode = uitreenode(app.Tree);
            app.BetweenSubjectsFactorsNode.NodeData = 'B';
            app.BetweenSubjectsFactorsNode.Text = 'Between-Subjects Factors';
            app.MeasuresNode = uitreenode(app.Tree);
            app.MeasuresNode.NodeData = 'M';
            app.MeasuresNode.Text = 'Measures';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FactorTab = uitab(app.TabGroup);
            app.FactorTab.Title = 'Factor';
            app.Tree2 = uitree(app.FactorTab);
            app.Tree2.Editable = 'on';
            app.LevelsLabel = uilabel(app.FactorTab);
            app.LevelsLabel.Text = 'Levels';
            app.DeleteFactorButton = uibutton(app.FactorTab, 'push');
            app.DeleteFactorButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFactorOrMeasure, true);
            app.DeleteFactorButton.Text = 'Delete Factor';
            app.AddLevelButton = uibutton(app.FactorTab, 'push');
            app.AddLevelButton.ButtonPushedFcn = createCallbackFcn(app, @AddLevelButtonPushed, true);
            app.AddLevelButton.Text = 'Add Level';
            app.RemoveLevelButton = uibutton(app.FactorTab, 'push');
            app.RemoveLevelButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLevelButtonPushed, true);
            app.RemoveLevelButton.Text = 'Remove Level';
            app.MeasureTab = uitab(app.TabGroup);
            app.MeasureTab.Title = 'Measure';
            app.ButtonGroup = uibuttongroup(app.MeasureTab);
            app.ScaleButton = uitogglebutton(app.ButtonGroup);
            app.ScaleButton.Text = 'Scale';
            app.ScaleButton.Value = true;
            app.OrdinalButton = uitogglebutton(app.ButtonGroup);
            app.OrdinalButton.Text = 'Ordinal';
            app.NominalButton = uitogglebutton(app.ButtonGroup);
            app.NominalButton.Text = 'Nominal';
            app.MeasureTypeLabel = uilabel(app.MeasureTab);
            app.MeasureTypeLabel.Text = 'Measure Type';
            app.DeleteMeasureButton = uibutton(app.MeasureTab, 'push');
            app.DeleteMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFactorOrMeasure, true);
            app.DeleteMeasureButton.Text = 'Delete Measure';
            app.Tab = uitab(app.TabGroup);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.WithinSubjectsButton = uibutton(app.Panel, 'push');
            app.WithinSubjectsButton.ButtonPushedFcn = createCallbackFcn(app, @AddWSFactor, true);
            app.WithinSubjectsButton.Text = 'Within-Subjects';
            app.BetweenSubjectsButton = uibutton(app.Panel, 'push');
            app.BetweenSubjectsButton.ButtonPushedFcn = createCallbackFcn(app, @BetweenSubjectsButtonPushed, true);
            app.BetweenSubjectsButton.Text = 'Between-Subjects';
            app.AddFactorButton = uibutton(app.UIFigure, 'push');
            app.AddFactorButton.ButtonPushedFcn = createCallbackFcn(app, @AddFactorButtonPushed, true);
            app.AddFactorButton.Text = 'Add Factor';
            app.AddMeasureButton = uibutton(app.UIFigure, 'push');
            app.AddMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @AddMeasureButtonPushed, true);
            app.AddMeasureButton.Text = 'Add Measure';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Design

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end