classdef Element < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ElementSpec              matlab.ui.container.Panel
        ElementName              matlab.ui.control.EditField
        DurationmsLabel          matlab.ui.control.Label
        DurationSpinner          matlab.ui.control.Spinner
        NoResponseLimit          matlab.ui.control.CheckBox
        FactorsButton            matlab.ui.control.StateButton
        StimPos                  matlab.ui.container.Panel
        Window                   matlab.ui.control.UIAxes
        Slider_X                 matlab.ui.control.Slider
        Slider_Y                 matlab.ui.control.Slider
        YPos                     matlab.ui.container.Panel
        CenterYButton            matlab.ui.control.Button
        Spinner_Y                matlab.ui.control.Spinner
        XPos                     matlab.ui.container.Panel
        Spinner_X                matlab.ui.control.Spinner
        CenterXButton            matlab.ui.control.Button
        MoveButton               matlab.ui.control.StateButton
        Scale                    matlab.ui.container.Panel
        ScaleSpinnerLabel        matlab.ui.control.Label
        Spinner_Scale            matlab.ui.control.Spinner
        ScreenSpec               matlab.ui.container.Panel
        ScreenWidthSpinnerLabel  matlab.ui.control.Label
        ScreenWidth              matlab.ui.control.Spinner
        ScreenHeightLabel        matlab.ui.control.Label
        ScreenHeight             matlab.ui.control.Spinner
        BlueSpinnerLabel         matlab.ui.control.Label
        BlueSpinner              matlab.ui.control.Spinner
        GreenSpinnerLabel        matlab.ui.control.Label
        GreenSpinner             matlab.ui.control.Spinner
        RedSpinnerLabel          matlab.ui.control.Label
        RedSpinner               matlab.ui.control.Spinner
        WarningLabel             matlab.ui.control.Label
        DoneButton               matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
        StimuliPanel             matlab.ui.container.Panel
        StimulusNode             matlab.ui.container.Panel
        StimulusTypeLabel        matlab.ui.control.Label
        StimType                 matlab.ui.control.ListBox
        FileLabel                matlab.ui.control.Label
        StimFileSelection        matlab.ui.control.Button
        FileName                 matlab.ui.control.EditField
        StimName                 matlab.ui.control.EditField
        OrderLabel               matlab.ui.control.Label
        StimOrder                matlab.ui.control.DropDown
        DeleteStim               matlab.ui.control.Button
        AddStimulusButton        matlab.ui.control.Button
        MeasuresPanel            matlab.ui.container.Panel
        MeasureNode              matlab.ui.container.Panel
        ButtonCoding             matlab.ui.control.Table
        RemoveButtonMap          matlab.ui.control.Button
        NewButtonMap             matlab.ui.control.Button
        ResponsesMeasureLabel    matlab.ui.control.Label
        ResponsesMeasureListBox  matlab.ui.control.ListBox
        DeleteMeasure            matlab.ui.control.Button
        AddMeasureButton         matlab.ui.control.Button
        Factors                  matlab.ui.container.Panel
        BetweenNode              matlab.ui.container.Panel
        Factor1ListBox_2Label    matlab.ui.control.Label
        BetweenList              matlab.ui.control.ListBox
        WithinNode               matlab.ui.container.Panel
        Factor1ListBox_3Label    matlab.ui.control.Label
        WithinList               matlab.ui.control.ListBox
        WithinSubjectsLabel      matlab.ui.control.Label
        BetweenSubjectsLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        Stimuli % Handles & parameters for stimuli
        Measures % Handles & parameters for measures
        Screen % Screen parameters
        Controls % Controls for each stimulus
        Parent %Parent object to pass info to
        Design %Store experiment design from design file
    end
    
    methods (Access = public)
        function refreshStimPos(app)
            % Apply screen spec
            app.Screen.Scale = min(app.StimPos.Position(3:4)./app.Screen.Dim); %how much to shrink the screen by in the sim
            app.Window.Position(3:4) = app.Screen.Dim.*app.Screen.Scale; %re-scale window
            app.Window.XLim = [0, app.Screen.Dim(1)];
            app.Window.YLim = [0, app.Screen.Dim(2)];
            app.Window.BackgroundColor = app.Screen.Background;
            
            %Set limits on screen size
            xlimguide = NaN(1,length([app.Stimuli])-1);
            ylimguide = NaN(1,length([app.Stimuli])-1);
            for n = 1:length([app.Stimuli])-1
                xlimguide(n) = app.Stimuli(n).ogDim(1)*app.Stimuli(n).Scale;
                ylimguide(n) = app.Stimuli(n).ogDim(2)*app.Stimuli(n).Scale;
            end
            app.ScreenWidth.Limits(1) = max([xlimguide,100]) + 10;
            app.ScreenHeight.Limits(1) = max([ylimguide,100]) + 10;
            app.Screen.Dim = [max(app.ScreenWidth.Limits(1), app.Screen.Dim(1)), max(app.ScreenHeight.Limits(1), app.Screen.Dim(2))];
            app.ScreenWidth.Value = app.Screen.Dim(1);
            app.ScreenHeight.Value = app.Screen.Dim(2);
                

            for n = 1:length([app.Stimuli])-1
                %Get x, y, w, h and scale for current stim
                x = app.Stimuli(n).Pos(1);
                y = app.Stimuli(n).Pos(2);
                w = app.Stimuli(n).ogDim(1);
                h = app.Stimuli(n).ogDim(2);
                s = app.Stimuli(n).Scale;
                
                %Define a buffer to apply to each side of each slider to prevent stim going off screen
                xBuffer = ceil(w*s/2);
                yBuffer = ceil(h*s/2);
                      
                %Limit position controls to the screen size - buffers
                app.Controls(n).XPos.Children(2).Limits = [xBuffer, app.Screen.Dim(1) - xBuffer];
                app.Controls(n).XSlider.Limits = [xBuffer, app.Screen.Dim(1) - xBuffer];
                app.Controls(n).YSlider.Limits = [yBuffer, app.Screen.Dim(2) - yBuffer];
                app.Controls(n).YPos.Children(1).Limits = [yBuffer, app.Screen.Dim(2) - yBuffer];
                app.Controls(n).Scale.Children(1).Limits(2) = floor(10/max([w,h]./app.Screen.Dim)) /10;
                
                %Adjust position if it's already outside buffers
                if x < xBuffer
                    x = xBuffer + 1;
                elseif x > app.Screen.Dim(1) - xBuffer
                    x = app.Screen.Dim(1) - xBuffer - 1;
                end
                
                if y < yBuffer
                    y = yBuffer + 1;
                elseif y > app.Screen.Dim(2) - yBuffer
                    y = app.Screen.Dim(2) - yBuffer - 1;
                end
            
                %Set values of position controls
                app.Controls(n).XSlider.Value = x;
                app.Controls(n).XPos.Children(2).Value = x;
                app.Controls(n).YSlider.Value = y;
                app.Controls(n).YPos.Children(1).Value = y;
                app.Controls(n).Scale.Children(1).Value = s;
                
                %Move position controls
                app.Controls(n).XSlider.Position = [xBuffer*app.Screen.Scale, app.Screen.Dim(2)*app.Screen.Scale - 3, (app.Screen.Dim(1) - xBuffer*2)*app.Screen.Scale, 3];
                app.Controls(n).YSlider.Position = [1, yBuffer*app.Screen.Scale, 3, (app.Screen.Dim(2) - yBuffer*2)*app.Screen.Scale];
                app.Controls(n).YPos.Position(1:2) = [15, y*app.Screen.Scale - app.Controls(n).YPos.Position(4)/2];
                app.Controls(n).XPos.Position(1:2) = [x*app.Screen.Scale - app.Controls(n).XPos.Position(3)/2, app.Screen.Dim(2)*app.Screen.Scale - app.Controls(n).XPos.Position(3) - 15];
                
                %Move stimulus and scale / move controls
                app.Stimuli(n).Plot.Position = [x - w*s/2, y - h*s/2, w*s, h*s];
                app.Controls(n).Scale.Position(1:2) = [(x + app.Stimuli(n).Plot.Position(3)/2)*app.Screen.Scale + 5, y*app.Screen.Scale - app.Controls(n).Scale.Position(4)/2];
                app.Controls(n).Move.Position(1:2) = [(x - w*s/2)*app.Screen.Scale + 5, (y - h*s/2)*app.Screen.Scale + 5];
            end
        end
    
        function refreshNodes(app)
            shift = app.MeasureNode.Position(3) + 25;
            for n = 1:length(app.Measures)-1
                app.Measures(n).Node.Position(1) = app.MeasureNode.Position(1) + shift*(n-1);
                app.Measures(n).Node.Children(2).Items = {app.Design.Measures(1:end-1).Name};
            end
            app.AddMeasureButton.Position(1) = app.MeasureNode.Position(1) + shift*(length(app.Measures) - 1);
            
            shift = app.StimulusNode.Position(3) + 25;
            for n = 1:length(app.Controls)-1
                app.Controls(n).Node.Position(1) = app.StimulusNode.Position(1) + shift*(n-1);
            end
            app.AddStimulusButton.Position(1) = app.StimulusNode.Position(1) + shift*(length(app.Stimuli) - 1);
        end    
    
        function stim = findStim(app, event)
            fieldArray = fields(app.Controls);
            fieldScan = NaN(length(app.Controls)-1,length(fieldArray));
            for f = 1:length(fieldArray)
                fieldScan(:, f) = ([app.Controls.(fieldArray{f})] == [event.Source]) + ([app.Controls.(fieldArray{f})] == [event.Source.Parent]); %which stimulus does it pertain to?
            end
            [stim, ~] = find(fieldScan);
        end
        
        function applyStimSpec(app, varargin)
            for n = 1:length([app.Stimuli]) - 1
                app.Stimuli(n).Name = app.Controls(n).Name.Value;
                app.Stimuli(n).Type = app.Controls(n).Type.Value;
                app.Stimuli(n).Order = app.Controls(n).Order.Value;
                app.Stimuli(n).Dir = app.Controls(n).Dir.Value;
                try
                [y, x, ~] = size(imread(app.Stimuli(n).Dir));
                app.Stimuli(n).ogDim = [x, y];
                catch
                    app.Stimuli(n).ogDim = [100, 100];
                end
            end
        end


        function el = saveobj(app)
            el = struct('ElementName', app.ElementName.Value, ...
                'Stimuli', app.Stimuli, ...
                'Dur', [app.DurationSpinner.Value, app.NoResponseLimit.Value], ...
                'Measures', app.Measures, ...
                'Screen', app.Screen, ...
                'Controls', app.Controls, ...
                'Design', app.Design ...
                );
        end
        
        function loadobj(app, el)
            %Load element-wide features
            app.ElementName.Value = el.ElementName;
            app.DurationSpinner.Value = el.Dur(1);
            app.NoResponseLimit.Value = el.Dur(2);
          
            %Load screen spec
            app.Screen = el.Screen;
            
            %Load stimuli
            for n = 1:length(el.Stimuli)
                %Add new stimulus
                AddStimulusButtonPushed(app, struct("Source", app.AddStimulusButton))
                
                %Apply values to stimulus
                app.Stimuli(n).Name = el.Stimuli(n).Name;
                app.Stimuli(n).Type = el.Stimuli(n).Type;
                app.Stimuli(n).Order = el.Stimuli(n).Order;
                app.Stimuli(n).Dir = el.Stimuli(n).Dir;
                app.Stimuli(n).ogDim = el.Stimuli(n).ogDim;
                app.Stimuli(n).Scale = el.Stimuli(n).Scale;
                app.Stimuli(n).Pos = el.Stimuli(n).Pos;
                
                %Apply values to control panel
                app.Controls(n).Name.Value = el.Controls(n).Name.Value;
                app.Controls(n).Type.Value = el.Controls(n).Type.Value;
                app.Controls(n).Order.Value = el.Controls(n).Order.Value;
                app.Controls(n).Dir.Value = el.Controls(n).Dir.Value;
            end
        end
        
    end
    
    methods (Access = private)
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.Window);
            
            wDim = get(groot, 'Screensize');
            
            app.Controls = struct("Name", [], "Type", [], "Order", [], "Dir", [], "Scale", [], "XSlider", [], "YSlider", [], "XPos", [], "YPos", [], "Move", [], "Node", []);
            app.Stimuli = struct("Name", [], "Type", [], "Order", [], "Dir", [], "Plot", [], "ogDim", [], "Scale", [], "Pos", []);
            app.Measures = struct("DV", [], "Map", [], "Node", []);
            app.Screen = struct("Dim", wDim(3:4), "Background", [app.RedSpinner.Value, app.GreenSpinner.Value, app.BlueSpinner.Value], "Scale", min(app.StimPos.Position(3:4)./wDim(3:4)));
            app.Design = struct("Within", struct("Data", [], "Node", []), "Between", struct("Data", [], "Node", []), "Measures", []);
            
            %Setup factors
            load('Design\Design.mat');
            app.Design.Within.Data = des.WS_Factors;
            app.Design.Between.Data = des.BS_Factors;
            app.Design.Measures = des.Measures;
            app.Factors.Position(3) = min( ...
                app.Factors.Position(3) + (app.BetweenNode.Position(3) + 15)*(max(length(app.Design.Between.Data), length(app.Design.Within.Data))-1), ...
                700);
            for n = 1:length(app.Design.Between.Data)-1
                app.Design.Between.Node{n} = copyobj(app.BetweenNode, app.Factors);
                app.Design.Between.Node{n}.Children(1).Items = [{'None'}; app.Design.Between.Data(n).Levels];
                app.Design.Between.Node{n}.Children(2).Text = app.Design.Between.Data(n).Name;
                app.Design.Between.Node{n}.Position(1) = app.Design.Between.Node{n}.Position(1) + (app.Design.Between.Node{n}.Position(4) + 15)*(n-1);
            end
            for n = 1:length(app.Design.Within.Data)-1
                app.Design.Within.Node{n} = copyobj(app.WithinNode, app.Factors);
                app.Design.Within.Node{n}.Children(1).Items = [{'None'}; app.Design.Within.Data(n).Levels];
                app.Design.Within.Node{n}.Children(2).Text = app.Design.Within.Data(n).Name;
                app.Design.Within.Node{n}.Position(1) = app.Design.Within.Node{n}.Position(1) + (app.Design.Within.Node{n}.Position(3) + 15)*(n-1);
            end


            refreshStimPos(app)
        end

        % Callback function: Spinner_Scale, Spinner_Scale
        function Spinner_ScaleValueChanged(app, event)
            n = findStim(app, event);
            app.Stimuli(n).Scale = event.Value;
            refreshStimPos(app)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            refreshStimPos(app);
            saveobj(app);
            delete(app);
        end

        % Callback function: RedSpinner, RedSpinner
        function RSpinnerValueChanged(app, event)
            app.Screen.Background(1) = event.Value;
            refreshStimPos(app)
        end

        % Callback function: GreenSpinner, GreenSpinner
        function GSpinnerValueChanged(app, event)
            app.Screen.Background(2) = event.Value;
            refreshStimPos(app)
        end

        % Callback function: BlueSpinner, BlueSpinner
        function BSpinnerValueChanged(app, event)
            app.Screen.Background(3) = event.Value;
            refreshStimPos(app)
        end

        % Button pushed function: StimFileSelection
        function StimFileSelectionButtonPushed(app, event)
            n = findStim(app, event);
            [name, loc] = uigetfile('*');
            app.Controls(n).Dir.Value = name;
            app.Stimuli(n).Dir = [loc, name];
            [y, x, ~] = size(imread(app.Stimuli(n).Dir));
            app.Stimuli(n).ogDim = [y, x]; %Stimulus size from file
            applyStimSpec(app)
            refreshStimPos(app)
            
        end

        % Button pushed function: NewButtonMap
        function NewButtonMapPushed(app, event)
            event.Source.Parent.Children(end).Data = [event.Source.Parent.Children(end).Data; {'Button'}, {'Code'}];
        end

        % Button pushed function: RemoveButtonMap
        function RemoveButtonMapPushed(app, event)
            event.Source.Parent.Children(end).Data = event.Source.Parent.Children(end).Data(1:end-1,:);
            mes = [app.Measures.Node] == [event.Source.Parent];
            app.Measures(mes).Map = event.Source.Parent.Children(end).Data;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: ScreenWidth
        function ScreenWidthValueChanged(app, event)
            app.Screen.Dim(1) = event.Value;
            refreshStimPos(app)
        end

        % Value changed function: ScreenHeight
        function ScreenHeightValueChanged(app, event)
            app.Screen.Dim(2) = event.Value;
            refreshStimPos(app)
        end

        % Callback function: Slider_X, Slider_X, Spinner_X, Spinner_X
        function updateX(app, event)
            n = findStim(app, event);
            app.Stimuli(n).Pos(1) = event.Value;
            refreshStimPos(app)
        end

        % Callback function: Slider_Y, Slider_Y, Spinner_Y, Spinner_Y
        function updateY(app, event)
            n = findStim(app, event);
            app.Stimuli(n).Pos(2) = event.Value;
            refreshStimPos(app)
        end

        % Button pushed function: CenterYButton
        function CenterYButtonPushed(app, event)
            n = findStim(app, event);
            app.Stimuli(n).Pos(2) = app.Screen.Dim(2)/2;
            refreshStimPos(app)
        end

        % Button pushed function: CenterXButton
        function CenterXButtonPushed(app, event)
            n = findStim(app, event);
            app.Stimuli(n).Pos(1) = app.Screen.Dim(1)/2;
            refreshStimPos(app)
        end

        % Button pushed function: AddMeasureButton
        function AddMeasureButtonPushed(app, event)
            n = length(app.Measures);
            
            app.Measures(n).Node = copyobj(app.MeasureNode,app.MeasuresPanel, 'legacy');
            app.Measures(n).Node.Visible = 'on';
            app.Measures(n+1).Node = [];
            
            refreshNodes(app)
        end

        % Button pushed function: AddStimulusButton
        function AddStimulusButtonPushed(app, event)
            n = length(app.Stimuli);
            
            % New stimspec node
            app.Controls(n).Node = copyobj(app.StimulusNode,app.StimuliPanel, 'legacy');
            app.Controls(n).Name = app.Controls(n).Node.Children(4);
            app.Controls(n).Type = app.Controls(n).Node.Children(8);
            app.Controls(n).Order = app.Controls(n).Node.Children(2);
            app.Controls(n).Dir = app.Controls(n).Node.Children(5);
            
            % New stimpos controls
            app.Controls(n).YPos = copyobj(app.YPos,app.StimPos, 'legacy');
            app.Controls(n).XPos = copyobj(app.XPos,app.StimPos, 'legacy');
            app.Controls(n).Scale = copyobj(app.Scale,app.StimPos, 'legacy');
            app.Controls(n).YSlider = copyobj(app.Slider_Y,app.StimPos, 'legacy');
            app.Controls(n).XSlider = copyobj(app.Slider_X,app.StimPos, 'legacy');
            app.Controls(n).Move = copyobj(app.MoveButton,app.StimPos, 'legacy');
            
            % Make new controls visible
            app.Controls(n).Node.Visible = 'on';
            app.Controls(n).Move.Visible = 'on';
            
            % Add row for next stim
            app.Controls(n+1).Node = [];
            app.Stimuli(n+1).Dir = [];
            refreshNodes(app)
            
            % Define default pos values
            app.Stimuli(n).Dir = 'Splash.png';
            app.Stimuli(n).Pos = [500,500];
            app.Stimuli(n).Scale = 1;
            
            % Draw new stimulus
            app.Stimuli(n).Plot = rectangle(app.Window, ...
    'Position',[0, 0, 500, 500].*app.Screen.Scale, ...
    'FaceColor',[0,33,71]./255, ...
    'EdgeColor',[0,33,71]./255);
            
            applyStimSpec(app)
            
            refreshStimPos(app)
        end

        % Value changed function: MoveButton
        function MoveButtonValueChanged(app, event)
            n = findStim(app, event);
            if event.Value == true
                app.Controls(n).YPos.Visible = 'on';
                app.Controls(n).XPos.Visible = 'on';
                app.Controls(n).YSlider.Visible = 'on';
                app.Controls(n).XSlider.Visible = 'on';
                app.Controls(n).Scale.Visible = 'on';
                app.Controls(n).Node.BackgroundColor = [0.96, 0.96, 0.96];
                
                other = find([app.Controls(n).Move] ~= [app.Controls.Move]);
                for x = other
                    app.Controls(x).YPos.Visible = 'off';
                    app.Controls(x).XPos.Visible = 'off';
                    app.Controls(x).YSlider.Visible = 'off';
                    app.Controls(x).XSlider.Visible = 'off';
                    app.Controls(x).Scale.Visible = 'off';
                    app.Controls(x).Node.BackgroundColor = [1,1,1];
                    app.Controls(x).Move.Value = false;
                end 
            else
                app.Controls(n).YPos.Visible = 'off';
                app.Controls(n).XPos.Visible = 'off';
                app.Controls(n).YSlider.Visible = 'off';
                app.Controls(n).XSlider.Visible = 'off';
                app.Controls(n).Scale.Visible = 'off';
                app.Controls(n).Node.BackgroundColor = [1,1,1];
            end
        end

        % Button pushed function: DeleteStim
        function DeleteStimButtonPushed(app, event)
            n = findStim(app, event);
            %Delete controls
            delete(app.Controls(n).Node)
            delete(app.Controls(n).YPos)
            delete(app.Controls(n).XPos)
            delete(app.Controls(n).Scale)
            delete(app.Controls(n).YSlider)
            delete(app.Controls(n).XSlider)
            delete(app.Controls(n).Move)
            
            %Delete stim
            delete(app.Stimuli(n).Plot)
            
            %Delete rows
            app.Stimuli(n) = [];
            app.Controls(n) = [];
            
            %Reposition
            refreshNodes(app)
        end

        % Button pushed function: DeleteMeasure
        function DeleteMeasureButtonPushed(app, event)
            n = [app.Measures.Node] == event.Source.Parent;
            delete(app.Measures(n).Node)
            app.Measures(n) = [];
            refreshNodes(app)
        end

        % Cell edit callback: ButtonCoding
        function ButtonCodingCellEdit(app, event)
            mes = [app.Measures.Node] == [event.Source.Parent];
            app.Measures(mes).Map = event.Source.Parent.Children(end).Data;
        end

        % Value changed function: FactorsButton
        function FactorsButtonValueChanged(app, event)
                app.Factors.Visible = event.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ElementSpec = uipanel(app.UIFigure);
            app.ElementName = uieditfield(app.ElementSpec, 'text');
            app.ElementName.Value = 'Element Name';
            app.DurationmsLabel = uilabel(app.ElementSpec);
            app.DurationmsLabel.Text = 'Duration (ms):';
            app.DurationSpinner = uispinner(app.ElementSpec);
            app.DurationSpinner.Step = 100;
            app.DurationSpinner.RoundFractionalValues = 'on';
            app.DurationSpinner.Value = 100;
            app.NoResponseLimit = uicheckbox(app.ElementSpec);
            app.NoResponseLimit.Text = 'No Limit';
            app.FactorsButton = uibutton(app.ElementSpec, 'state');
            app.FactorsButton.ValueChangedFcn = createCallbackFcn(app, @FactorsButtonValueChanged, true);
            app.FactorsButton.Text = 'Assign to conditions...';
            app.StimPos = uipanel(app.UIFigure);
            app.Window = uiaxes(app.StimPos);
            app.Window.XTick = [];
            app.Window.YTick = [];
            app.Window.Visible = 'off';
            app.Slider_X = uislider(app.StimPos);
            app.Slider_X.Limits = [1 100];
            app.Slider_X.MajorTicks = [];
            app.Slider_X.MajorTickLabels = {'1'};
            app.Slider_X.ValueChangedFcn = createCallbackFcn(app, @updateX, true);
            app.Slider_X.ValueChangingFcn = createCallbackFcn(app, @updateX, true);
            app.Slider_X.MinorTicks = [];
            app.Slider_X.Visible = 'off';
            app.Slider_X.Value = 50;
            app.Slider_Y = uislider(app.StimPos);
            app.Slider_Y.Limits = [1 100];
            app.Slider_Y.MajorTicks = [];
            app.Slider_Y.ValueChangedFcn = createCallbackFcn(app, @updateY, true);
            app.Slider_Y.ValueChangingFcn = createCallbackFcn(app, @updateY, true);
            app.Slider_Y.MinorTicks = [];
            app.Slider_Y.Visible = 'off';
            app.Slider_Y.Value = 50;
            app.YPos = uipanel(app.StimPos);
            app.YPos.Visible = 'off';
            app.CenterYButton = uibutton(app.YPos, 'push');
            app.CenterYButton.ButtonPushedFcn = createCallbackFcn(app, @CenterYButtonPushed, true);
            app.CenterYButton.Text = 'Center';
            app.Spinner_Y = uispinner(app.YPos);
            app.Spinner_Y.ValueChangingFcn = createCallbackFcn(app, @updateY, true);
            app.Spinner_Y.ValueChangedFcn = createCallbackFcn(app, @updateY, true);
            app.Spinner_Y.Value = 50;
            app.XPos = uipanel(app.StimPos);
            app.XPos.Visible = 'off';
            app.Spinner_X = uispinner(app.XPos);
            app.Spinner_X.ValueChangingFcn = createCallbackFcn(app, @updateX, true);
            app.Spinner_X.ValueChangedFcn = createCallbackFcn(app, @updateX, true);
            app.Spinner_X.Value = 50;
            app.CenterXButton = uibutton(app.XPos, 'push');
            app.CenterXButton.ButtonPushedFcn = createCallbackFcn(app, @CenterXButtonPushed, true);
            app.CenterXButton.Text = 'Center';
            app.MoveButton = uibutton(app.StimPos, 'state');
            app.MoveButton.ValueChangedFcn = createCallbackFcn(app, @MoveButtonValueChanged, true);
            app.MoveButton.Visible = 'off';
            app.MoveButton.Tooltip = {'Move stimulus'};
            app.MoveButton.Icon = 'Move.png';
            app.MoveButton.Text = '';
            app.Scale = uipanel(app.StimPos);
            app.Scale.Visible = 'off';
            app.ScaleSpinnerLabel = uilabel(app.Scale);
            app.ScaleSpinnerLabel.Text = 'Scale';
            app.Spinner_Scale = uispinner(app.Scale);
            app.Spinner_Scale.Step = 0.1;
            app.Spinner_Scale.ValueChangingFcn = createCallbackFcn(app, @Spinner_ScaleValueChanged, true);
            app.Spinner_Scale.Limits = [0.1 Inf];
            app.Spinner_Scale.ValueChangedFcn = createCallbackFcn(app, @Spinner_ScaleValueChanged, true);
            app.Spinner_Scale.Value = 1;
            app.ScreenSpec = uipanel(app.UIFigure);
            app.ScreenWidthSpinnerLabel = uilabel(app.ScreenSpec);
            app.ScreenWidthSpinnerLabel.Text = 'Screen Width:';
            app.ScreenWidth = uispinner(app.ScreenSpec);
            app.ScreenWidth.Limits = [100 Inf];
            app.ScreenWidth.ValueChangedFcn = createCallbackFcn(app, @ScreenWidthValueChanged, true);
            app.ScreenWidth.Value = 100;
            app.ScreenHeightLabel = uilabel(app.ScreenSpec);
            app.ScreenHeightLabel.Text = 'Screen Height:';
            app.ScreenHeight = uispinner(app.ScreenSpec);
            app.ScreenHeight.Limits = [100 Inf];
            app.ScreenHeight.ValueChangedFcn = createCallbackFcn(app, @ScreenHeightValueChanged, true);
            app.ScreenHeight.Value = 100;
            app.BlueSpinnerLabel = uilabel(app.ScreenSpec);
            app.BlueSpinnerLabel.Text = 'Blue';
            app.BlueSpinner = uispinner(app.ScreenSpec);
            app.BlueSpinner.Step = 0.01;
            app.BlueSpinner.ValueChangingFcn = createCallbackFcn(app, @BSpinnerValueChanged, true);
            app.BlueSpinner.Limits = [0 1];
            app.BlueSpinner.ValueChangedFcn = createCallbackFcn(app, @BSpinnerValueChanged, true);
            app.BlueSpinner.Value = 0.5;
            app.GreenSpinnerLabel = uilabel(app.ScreenSpec);
            app.GreenSpinnerLabel.Text = 'Green';
            app.GreenSpinner = uispinner(app.ScreenSpec);
            app.GreenSpinner.Step = 0.01;
            app.GreenSpinner.ValueChangingFcn = createCallbackFcn(app, @GSpinnerValueChanged, true);
            app.GreenSpinner.Limits = [0 1];
            app.GreenSpinner.ValueChangedFcn = createCallbackFcn(app, @GSpinnerValueChanged, true);
            app.GreenSpinner.Value = 0.5;
            app.RedSpinnerLabel = uilabel(app.ScreenSpec);
            app.RedSpinnerLabel.Text = 'Red';
            app.RedSpinner = uispinner(app.ScreenSpec);
            app.RedSpinner.Step = 0.01;
            app.RedSpinner.ValueChangingFcn = createCallbackFcn(app, @RSpinnerValueChanged, true);
            app.RedSpinner.Limits = [0 1];
            app.RedSpinner.ValueChangedFcn = createCallbackFcn(app, @RSpinnerValueChanged, true);
            app.RedSpinner.Value = 0.5;
            app.WarningLabel = uilabel(app.ScreenSpec);
            app.WarningLabel.Visible = 'off';
            app.WarningLabel.Text = 'Warning: ';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.StimuliPanel = uipanel(app.UIFigure);
            app.StimuliPanel.Title = 'Stimuli';
            app.StimulusNode = uipanel(app.StimuliPanel);
            app.StimulusNode.Visible = 'off';
            app.StimulusTypeLabel = uilabel(app.StimulusNode);
            app.StimulusTypeLabel.Text = 'Stimulus Type:';
            app.StimType = uilistbox(app.StimulusNode);
            app.StimType.Items = {'Image', 'Frames', 'Audio', 'Video', ''};
            app.StimType.Value = 'Image';
            app.FileLabel = uilabel(app.StimulusNode);
            app.FileLabel.Text = 'File:';
            app.StimFileSelection = uibutton(app.StimulusNode, 'push');
            app.StimFileSelection.ButtonPushedFcn = createCallbackFcn(app, @StimFileSelectionButtonPushed, true);
            app.StimFileSelection.Text = 'Browse';
            app.FileName = uieditfield(app.StimulusNode, 'text');
            app.FileName.Editable = 'off';
            app.FileName.Enable = 'off';
            app.FileName.Value = 'none.png';
            app.StimName = uieditfield(app.StimulusNode, 'text');
            app.StimName.Value = 'Stimulus Name';
            app.OrderLabel = uilabel(app.StimulusNode);
            app.OrderLabel.Text = 'Order:';
            app.StimOrder = uidropdown(app.StimulusNode);
            app.StimOrder.Items = {'Constant'};
            app.StimOrder.Value = 'Constant';
            app.DeleteStim = uibutton(app.StimulusNode, 'push');
            app.DeleteStim.ButtonPushedFcn = createCallbackFcn(app, @DeleteStimButtonPushed, true);
            app.DeleteStim.Text = 'x';
            app.AddStimulusButton = uibutton(app.StimuliPanel, 'push');
            app.AddStimulusButton.ButtonPushedFcn = createCallbackFcn(app, @AddStimulusButtonPushed, true);
            app.AddStimulusButton.Text = {'Add '; 'Stimulus'};
            app.MeasuresPanel = uipanel(app.UIFigure);
            app.MeasuresPanel.Title = 'Measures';
            app.MeasureNode = uipanel(app.MeasuresPanel);
            app.MeasureNode.Visible = 'off';
            app.ButtonCoding = uitable(app.MeasureNode);
            app.ButtonCoding.ColumnName = {'If they press...'; 'Record it as...'};
            app.ButtonCoding.RowName = {};
            app.ButtonCoding.ColumnEditable = true;
            app.ButtonCoding.CellEditCallback = createCallbackFcn(app, @ButtonCodingCellEdit, true);
            app.RemoveButtonMap = uibutton(app.MeasureNode, 'push');
            app.RemoveButtonMap.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonMapPushed, true);
            app.RemoveButtonMap.Text = '-';
            app.NewButtonMap = uibutton(app.MeasureNode, 'push');
            app.NewButtonMap.ButtonPushedFcn = createCallbackFcn(app, @NewButtonMapPushed, true);
            app.NewButtonMap.Text = '+';
            app.ResponsesMeasureLabel = uilabel(app.MeasureNode);
            app.ResponsesMeasureLabel.Text = 'Responses Measure...';
            app.ResponsesMeasureListBox = uilistbox(app.MeasureNode);
            app.ResponsesMeasureListBox.Items = {'None'};
            app.ResponsesMeasureListBox.Value = 'None';
            app.DeleteMeasure = uibutton(app.MeasureNode, 'push');
            app.DeleteMeasure.ButtonPushedFcn = createCallbackFcn(app, @DeleteMeasureButtonPushed, true);
            app.DeleteMeasure.Text = 'x';
            app.AddMeasureButton = uibutton(app.MeasuresPanel, 'push');
            app.AddMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @AddMeasureButtonPushed, true);
            app.AddMeasureButton.Text = {'Add '; 'Measure'};
            app.Factors = uipanel(app.UIFigure);
            app.Factors.Visible = 'off';
            app.BetweenNode = uipanel(app.Factors);
            app.Factor1ListBox_2Label = uilabel(app.BetweenNode);
            app.Factor1ListBox_2Label.Text = 'Factor 1';
            app.BetweenList = uilistbox(app.BetweenNode);
            app.BetweenList.Items = {'Item 1'};
            app.WithinNode = uipanel(app.Factors);
            app.Factor1ListBox_3Label = uilabel(app.WithinNode);
            app.Factor1ListBox_3Label.Text = 'Factor 1';
            app.WithinList = uilistbox(app.WithinNode);
            app.WithinList.Items = {'Item 1'};
            app.WithinSubjectsLabel = uilabel(app.Factors);
            app.WithinSubjectsLabel.Text = 'Within Subjects';
            app.BetweenSubjectsLabel = uilabel(app.Factors);
            app.BetweenSubjectsLabel.Text = 'Between Subjects';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Element

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end