classdef Routine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RoutineName              matlab.ui.control.EditField
        ElementsInRoutineLabel   matlab.ui.control.Label
        RoutineElements          matlab.ui.container.Tree
        REBlank                  matlab.ui.container.TreeNode
        UIAxes                   matlab.ui.control.UIAxes
        AllElementsLabel         matlab.ui.control.Label
        AllElements              matlab.ui.container.Tree
        AEBlank                  matlab.ui.container.TreeNode
        AddToRoutineButton       matlab.ui.control.Button
        RemoveFromRoutineButton  matlab.ui.control.Button
        StartatSlider            matlab.ui.control.Slider
    end

    
    methods (Access = public)
        
        function OrderEmts(app)
                nodes = [app.RoutineElements.Children.NodeData];
                ln = [nodes.Line];
                for l = length(ln):-1:1
                    ln(l).YData = [l,l];
                end
                app.UIAxes.YLim(2) = length(ln) + 1;
            end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            elementList = dir('Elements/*.mat');
            for e = 1:length(elementList)
                uitreenode(app.AllElements,  app.AEBlank, 'before', ...
                    'Text', elementList(e).name, ...
                    'Tag', 'E' ...
                    );
            end
        end

        % Button pushed function: AddToRoutineButton
        function AddToRoutineButtonPushed(app, event)
            node = uitreenode(app.RoutineElements,  app.REBlank, 'before', ...
                'Text', app.AllElements.SelectedNodes.Text, ...
                'NodeData', load(['Elements\', app.AllElements.SelectedNodes.Text], 'el'), ...
                'Tag', 'E' ...
                );
            layer = app.UIAxes.YLim(2);
            app.UIAxes.YLim(2) = layer + 1;
            node.NodeData.Line = plot(app.UIAxes, ...
                [0,node.NodeData.el.Dur(2)], [layer,layer], ...
                '-d', 'Color', [0,33/255,71/255], ...
                'LineWidth', 10); %draw element
            hold(app.UIAxes, 'on');
            
            OrderEmts(app)
            
        end

        % Callback function: StartatSlider, StartatSlider
        function StartatSliderValueChanged(app, event)
            node = app.RoutineElements.SelectedNodes;
            dur = node.NodeData.Line.XData(2) - node.NodeData.Line.XData(1);
            node.NodeData.Line.XData = [event.Value,event.Value + dur];
            
            app.UIAxes.XLim(2) = max(event.Value + dur, app.UIAxes.XLim(2));
        end

        % Selection changed function: RoutineElements
        function RoutineElementsSelectionChanged(app, event)
        if event.SelectedNodes ~= app.REBlank
            app.StartatSlider.Visible = ~isempty(event.SelectedNodes.NodeData);
            app.RemoveFromRoutineButton.Enable = ~isempty(event.SelectedNodes.NodeData);
            app.StartatSlider.Value = event.SelectedNodes.NodeData.Line.XData(1);
            
            event.SelectedNodes.NodeData.Line.Color = [0.90,0.00,0.49];
            try
                event.PreviousSelectedNodes.NodeData.Line.Color = [0 0.1294 0.2784];
            end
        end
        end

        % Button pushed function: RemoveFromRoutineButton
        function RemoveFromRoutineButtonPushed(app, event)
            if ~isempty(app.RoutineElements.SelectedNodes.NodeData)
                delete(app.RoutineElements.SelectedNodes.NodeData.Line)
                delete(app.RoutineElements.SelectedNodes)
            end
            
            if ~isempty([app.RoutineElements.Children.NodeData])
                OrderEmts(app)
            else
                app.StartatSlider.Visible = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RoutineName = uieditfield(app.UIFigure, 'text');
            app.RoutineName.Value = 'Routine Name';
            app.ElementsInRoutineLabel = uilabel(app.UIFigure);
            app.ElementsInRoutineLabel.Text = 'Elements In Routine';
            app.RoutineElements = uitree(app.UIFigure);
            app.RoutineElements.SelectionChangedFcn = createCallbackFcn(app, @RoutineElementsSelectionChanged, true);
            app.REBlank = uitreenode(app.RoutineElements);
            app.REBlank.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, {'Time (ms)'; ''})
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.YTick = 1;
            app.UIAxes.YTickLabel = '';
            app.AllElementsLabel = uilabel(app.UIFigure);
            app.AllElementsLabel.Text = 'All Elements';
            app.AllElements = uitree(app.UIFigure);
            app.AEBlank = uitreenode(app.AllElements);
            app.AEBlank.Text = '';
            app.AddToRoutineButton = uibutton(app.UIFigure, 'push');
            app.AddToRoutineButton.ButtonPushedFcn = createCallbackFcn(app, @AddToRoutineButtonPushed, true);
            app.AddToRoutineButton.Text = {'Add To '; 'Routine >>'};
            app.RemoveFromRoutineButton = uibutton(app.UIFigure, 'push');
            app.RemoveFromRoutineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFromRoutineButtonPushed, true);
            app.RemoveFromRoutineButton.Text = {'<< Remove'; 'From Routine'};
            app.StartatSlider = uislider(app.UIFigure);
            app.StartatSlider.MajorTicks = [];
            app.StartatSlider.ValueChangedFcn = createCallbackFcn(app, @StartatSliderValueChanged, true);
            app.StartatSlider.ValueChangingFcn = createCallbackFcn(app, @StartatSliderValueChanged, true);
            app.StartatSlider.MinorTicks = [];
            app.StartatSlider.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Routine

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end