classdef Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Tree                         matlab.ui.container.Tree
        WithinSubjectsFactorsNode    matlab.ui.container.TreeNode
        BetweenSubjectsFactorsNode   matlab.ui.container.TreeNode
        MeasuresNode                 matlab.ui.container.TreeNode
        TabGroup                     matlab.ui.container.TabGroup
        FactorTab                    matlab.ui.container.Tab
        Tree2                        matlab.ui.container.Tree
        LevelsLabel                  matlab.ui.control.Label
        DeleteFactorButton           matlab.ui.control.Button
        AddLevelButton               matlab.ui.control.Button
        RemoveLevelButton            matlab.ui.control.Button
        FactorName                   matlab.ui.control.EditField
        MeasureTab                   matlab.ui.container.Tab
        ButtonGroup                  matlab.ui.container.ButtonGroup
        ScaleButton                  matlab.ui.control.ToggleButton
        OrdinalButton                matlab.ui.control.ToggleButton
        NominalButton                matlab.ui.control.ToggleButton
        MeasureTypeLabel             matlab.ui.control.Label
        DeleteMeasureButton          matlab.ui.control.Button
        MeasureName                  matlab.ui.control.EditField
        NoneTab                      matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        WithinSubjectsFactorButton   matlab.ui.control.Button
        BetweenSubjectsFactorButton  matlab.ui.control.Button
        MeasureButton                matlab.ui.control.Button
        NewButton                    matlab.ui.control.Button
        DoneButton                   matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        DesignLabel                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        rootdir % Description
    end
    
    methods (Access = public)
        function loadobj(app, tree, des)
            % load BS factors
            i = strcmp({tree.NodeData}, 'B');
            par = tree(i);
            if ~isempty(fieldnames(Structure))
            for n = 1:length(des.BS_Factors)
                uitreenode(par,  ...
                    'Text', des.BS_Factors(n).Name, ...
                    'NodeData', des.BS_Factors(n).Levels, ...
                    'Tag', 'B' ...
                    );
            end
            
            % load WS Factors
            i = strcmp({tree.NodeData}, 'W');
            par = tree(i);
            for n = 1:length(des.WS_Factors)
                uitreenode(par,  ...
                    'Text', des.WS_Factors(n).Name, ...
                    'NodeData', des.WS_Factors(n).Levels, ...
                    'Tag', 'W' ...
                    );
            end
            
            % load Measures
            i = strcmp({tree.NodeData}, 'M');
            par = tree(i);
            for n = 1:length(des.Measures)
                uitreenode(par,  ...
                    'Text', des.Measures(n).Name, ...
                    'NodeData', des.Measures(n).Type, ...
                    'Tag', 'M' ...
                    );
            end
            end
        end
        
        function saveobj(app)
            M = app.Tree.Children(strcmp({app.Tree.Children.NodeData}, {'M'})).Children;
            B = app.Tree.Children(strcmp({app.Tree.Children.NodeData}, {'B'})).Children;
            W = app.Tree.Children(strcmp({app.Tree.Children.NodeData}, {'W'})).Children;
            
            des = struct('BS_Factors', struct('Name', {B.Text}', 'Levels', {B.NodeData}'), ...
                'WS_Factors', struct('Name', {W.Text}', 'Levels', {W.NodeData}'), ...
                'Measures', struct('Name', {M.Text}', 'Type', {M.NodeData}') ...
                );
            save([app.rootdir, '\Design\Design.mat'], 'des')
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.SelectedTab = app.NoneTab;
        end

        % Button pushed function: WithinSubjectsFactorButton
        function AddWSFactor2(app, event)
            fac = uitreenode(app.WithinSubjectsFactorsNode,  ...
                'Text', 'Factor Name...', ...
                'NodeData', struct('Text', {'Level 1 name...'; 'Level 2 name...'}, 'Tag', {'L'; 'L'}), ...
                'Tag', 'W' ...
                );
            expand(fac.Parent)
            app.Panel.Visible = false;
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            app.Panel.Visible = true;
        end

        % Button pushed function: BetweenSubjectsFactorButton
        function BetweenSubjectsFactorButtonPushed(app, event)
            fac = uitreenode(app.BetweenSubjectsFactorsNode,  ...
                'Text', 'Factor Name...', ...
                'NodeData', struct('Text', {'Level 1 name...'; 'Level 2 name...'}, 'Tag', {'L'; 'L'}), ...
                'Tag', 'B' ...
                );
            expand(fac.Parent)
            app.Panel.Visible = false;
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            mes = uitreenode(app.MeasuresNode,  ...
                'Text', 'Measure Name...', ...
                'NodeData', struct('Scale', true, 'Ordinal', false, 'Nominal', false), ...
                'Tag', 'M' ...
                );
            expand(mes.Parent)
            app.Panel.Visible = false;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            if ...
                    isequal(event.PreviousSelectedNodes, app.WithinSubjectsFactorsNode) || ...
                    isequal(event.PreviousSelectedNodes, app.BetweenSubjectsFactorsNode) || ....
                    isequal(event.PreviousSelectedNodes, app.MeasuresNode) || ...
                    isempty(event.PreviousSelectedNodes)
            else
                switch event.PreviousSelectedNodes.Tag %save previous selection
                    case {'W', 'B'}
                        try
                            event.PreviousSelectedNodes.NodeData = struct('Text', {app.Tree2.Children.Text}', 'Tag', {app.Tree2.Children.Tag}');
                        catch
                            event.PreviousSelectedNodes.NodeData = struct('Text', {}, 'Tag', {});
                        end
                        delete(app.Tree2.Children)
                    case 'M'
                        event.PreviousSelectedNodes.NodeData = struct('Scale', app.ScaleButton.Value, 'Ordinal', app.OrdinalButton.Value, 'Nominal', app.NominalButton.Value);
                end
            end
            
            if ...
                    isequal(event.SelectedNodes, app.WithinSubjectsFactorsNode) || ...
                    isequal(event.SelectedNodes, app.BetweenSubjectsFactorsNode) || ....
                    isequal(event.SelectedNodes, app.MeasuresNode) || ...
                    isempty(event.SelectedNodes)
                app.TabGroup.SelectedTab = app.NoneTab;
            else
                switch event.SelectedNodes.Tag %load new selection
                    case {'W', 'B'}
                        app.TabGroup.SelectedTab = app.FactorTab;
                        delete(app.Tree2.Children)
                        for n = 1:length(event.SelectedNodes.NodeData)
                            uitreenode(app.Tree2,  ...
                                'Text', event.SelectedNodes.NodeData(n).Text, ...
                                'Tag', event.SelectedNodes.NodeData(n).Tag ...
                                );
                        end
                        app.RemoveLevelButton.Enable = length(app.Tree2.Children) > 2;
                        app.FactorName.Value = event.SelectedNodes.Text;
                    case 'M'
                        app.TabGroup.SelectedTab = app.MeasureTab;
                        app.ScaleButton.Value = event.SelectedNodes.NodeData.Scale;
                        app.OrdinalButton.Value = event.SelectedNodes.NodeData.Ordinal;
                        app.NominalButton.Value = event.SelectedNodes.NodeData.Nominal;
                        app.MeasureName.Value = event.SelectedNodes.Text;
                end
            end


        end

        % Button pushed function: AddLevelButton
        function AddLevelButtonPushed(app, event)
            newNode = uitreenode(app.Tree2,  ...
                'Text', 'Level Name...', ...
                'Tag', 'L' ...
                );
            app.Tree.SelectedNodes.NodeData = app.Tree2.Children;
            app.RemoveLevelButton.Enable = length(app.Tree2.Children) > 2;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.TabGroup.SelectedTab = event.OldValue; %prevent changing tabs manually
        end

        % Button pushed function: RemoveLevelButton
        function RemoveLevelButtonPushed(app, event)
            delete(app.Tree2.SelectedNodes)
            app.RemoveLevelButton.Enable = length(app.Tree2.Children) > 2;
        end

        % Button pushed function: DeleteFactorButton, 
        % DeleteMeasureButton
        function DeleteFactorOrMeasure(app, event)
            delete(app.Tree.SelectedNodes)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            saveobj(app)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Callback function: FactorName, FactorName, MeasureName, 
        % MeasureName
        function EditFieldValueChanged(app, event)
            app.Tree.SelectedNodes.Text = event.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.WithinSubjectsFactorsNode = uitreenode(app.Tree);
            app.WithinSubjectsFactorsNode.NodeData = 'W';
            app.WithinSubjectsFactorsNode.Text = 'Within-Subjects Factors';
            app.BetweenSubjectsFactorsNode = uitreenode(app.Tree);
            app.BetweenSubjectsFactorsNode.NodeData = 'B';
            app.BetweenSubjectsFactorsNode.Text = 'Between-Subjects Factors';
            app.MeasuresNode = uitreenode(app.Tree);
            app.MeasuresNode.NodeData = 'M';
            app.MeasuresNode.Text = 'Measures';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FactorTab = uitab(app.TabGroup);
            app.FactorTab.Title = 'Factor';
            app.Tree2 = uitree(app.FactorTab);
            app.Tree2.Editable = 'on';
            app.LevelsLabel = uilabel(app.FactorTab);
            app.LevelsLabel.Text = 'Levels';
            app.DeleteFactorButton = uibutton(app.FactorTab, 'push');
            app.DeleteFactorButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFactorOrMeasure, true);
            app.DeleteFactorButton.Text = 'Delete Factor';
            app.AddLevelButton = uibutton(app.FactorTab, 'push');
            app.AddLevelButton.ButtonPushedFcn = createCallbackFcn(app, @AddLevelButtonPushed, true);
            app.AddLevelButton.Text = 'Add Level';
            app.RemoveLevelButton = uibutton(app.FactorTab, 'push');
            app.RemoveLevelButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLevelButtonPushed, true);
            app.RemoveLevelButton.Text = 'Remove Level';
            app.FactorName = uieditfield(app.FactorTab, 'text');
            app.FactorName.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.FactorName.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.FactorName.Value = 'Factor Name';
            app.MeasureTab = uitab(app.TabGroup);
            app.MeasureTab.Title = 'Measure';
            app.ButtonGroup = uibuttongroup(app.MeasureTab);
            app.ScaleButton = uitogglebutton(app.ButtonGroup);
            app.ScaleButton.Text = 'Scale';
            app.ScaleButton.Value = true;
            app.OrdinalButton = uitogglebutton(app.ButtonGroup);
            app.OrdinalButton.Text = 'Ordinal';
            app.NominalButton = uitogglebutton(app.ButtonGroup);
            app.NominalButton.Text = 'Nominal';
            app.MeasureTypeLabel = uilabel(app.MeasureTab);
            app.MeasureTypeLabel.Text = 'Measure Type';
            app.DeleteMeasureButton = uibutton(app.MeasureTab, 'push');
            app.DeleteMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFactorOrMeasure, true);
            app.DeleteMeasureButton.Text = 'Delete Measure';
            app.MeasureName = uieditfield(app.MeasureTab, 'text');
            app.MeasureName.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.MeasureName.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.MeasureName.Value = 'Measure Name';
            app.NoneTab = uitab(app.TabGroup);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.WithinSubjectsFactorButton = uibutton(app.Panel, 'push');
            app.WithinSubjectsFactorButton.ButtonPushedFcn = createCallbackFcn(app, @AddWSFactor2, true);
            app.WithinSubjectsFactorButton.Text = {'Within-Subjects'; 'Factor'};
            app.BetweenSubjectsFactorButton = uibutton(app.Panel, 'push');
            app.BetweenSubjectsFactorButton.ButtonPushedFcn = createCallbackFcn(app, @BetweenSubjectsFactorButtonPushed, true);
            app.BetweenSubjectsFactorButton.Text = {'Between-Subjects'; 'Factor'};
            app.MeasureButton = uibutton(app.Panel, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.NewButton = uibutton(app.UIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New...';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.DesignLabel = uilabel(app.UIFigure);
            app.DesignLabel.Text = 'Design';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Design

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end