classdef Element < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Window                        matlab.ui.control.UIAxes
        Tree                          matlab.ui.container.Tree
        StimuliNode                   matlab.ui.container.TreeNode
        ResponsesNode                 matlab.ui.container.TreeNode
        CodeNode                      matlab.ui.container.TreeNode
        ElementName                   matlab.ui.control.EditField
        TabGroup                      matlab.ui.container.TabGroup
        ImageTab                      matlab.ui.container.Tab
        PresentationOrderLabel        matlab.ui.control.Label
        StimOrder                     matlab.ui.control.DropDown
        DeleteStimButton              matlab.ui.control.Button
        ImgDimLab                     matlab.ui.control.Label
        Tree2                         matlab.ui.container.Tree
        StimuliLabel                  matlab.ui.control.Label
        PositionLabel                 matlab.ui.control.Label
        X_SpinnerLabel                matlab.ui.control.Label
        X_Spinner                     matlab.ui.control.Spinner
        X_CenterButton                matlab.ui.control.Button
        Y_SpinnerLabel                matlab.ui.control.Label
        Y_Spinner                     matlab.ui.control.Spinner
        Y_CenterButton                matlab.ui.control.Button
        XSpinnerLabel_3               matlab.ui.control.Label
        Scale_Spinner                 matlab.ui.control.Spinner
        KBRespTab                     matlab.ui.container.Tab
        MeasureName                   matlab.ui.control.EditField
        ButtonCoding                  matlab.ui.control.Table
        NewButtonMap                  matlab.ui.control.Button
        DeleteMeasure                 matlab.ui.control.Button
        EndroutineonresponseCheckBox  matlab.ui.control.CheckBox
        CodeTab                       matlab.ui.container.Tab
        DeleteCode                    matlab.ui.control.Button
        CodeTextAreaLabel             matlab.ui.control.Label
        CodeTextArea                  matlab.ui.control.TextArea
        CodingName                    matlab.ui.control.EditField
        EdulogTab                     matlab.ui.container.Tab
        LoggersButtonGroup            matlab.ui.container.ButtonGroup
        TemperatureButton             matlab.ui.control.RadioButton
        LightButton                   matlab.ui.control.RadioButton
        PulseButton                   matlab.ui.control.RadioButton
        SoundButton                   matlab.ui.control.RadioButton
        HumidityButton                matlab.ui.control.RadioButton
        MotionButton                  matlab.ui.control.RadioButton
        BloodPressureButton           matlab.ui.control.RadioButton
        HandDynamometerButton         matlab.ui.control.RadioButton
        GSRButton                     matlab.ui.control.RadioButton
        EKGButton                     matlab.ui.control.RadioButton
        PortEditFieldLabel            matlab.ui.control.Label
        PortEditField                 matlab.ui.control.EditField
        SamplesPerSecondSpinnerLabel  matlab.ui.control.Label
        SamplesPerSecondSpinner       matlab.ui.control.Spinner
        CodingName_2                  matlab.ui.control.EditField
        NoneTab                       matlab.ui.container.Tab
        X_Slider                      matlab.ui.control.Slider
        Y_Slider                      matlab.ui.control.Slider
        AddNodePanel                  matlab.ui.container.Panel
        StimulusButton                matlab.ui.control.Button
        CodeButton                    matlab.ui.control.Button
        MeasureButton                 matlab.ui.control.Button
        DurationmsLabel               matlab.ui.control.Label
        DurationSpinner               matlab.ui.control.Spinner
        NoResponseLimit               matlab.ui.control.CheckBox
        SaveButton                    matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        BlueSpinnerLabel              matlab.ui.control.Label
        BlueSpinner                   matlab.ui.control.Spinner
        GreenSpinnerLabel             matlab.ui.control.Label
        GreenSpinner                  matlab.ui.control.Spinner
        RedSpinnerLabel               matlab.ui.control.Label
        RedSpinner                    matlab.ui.control.Spinner
        FileLabel                     matlab.ui.control.Label
        FileName                      matlab.ui.control.EditField
        StimFileSelection             matlab.ui.control.Button
        NewButton                     matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        rootdir % Description
    end
    
    methods (Access = public)
        
        function loadStim(app)
            delete(app.Tree2.Children);
            stim = dir([app.rootdir, 'Stimuli']);
            stim = {stim.name};
            stim = stim(~strcmp(stim, '.') & ~strcmp(stim, '..'));
            for n = 1:length(stim)
                uitreenode(app.Tree2, ...
                'Text', stim{n}, ...
                'NodeData', [app.rootdir, 'Stimuli\', stim{n}], ...
                'Tag', 'Stim' ...
                );
            end
            
            
        end
        
        function ScreenSz(app, w, h)
            mw = 750;
            mh = 500;
            scale = min(mw / w, mh / h);
            
            app.Window.Position(3:4) = floor([w*scale, h*scale]);
            app.Window.XLim(2) = w;
            app.Window.YLim(2) = h;
            
            app.X_Slider.Position = floor([...
                app.Window.Position(1), ... x
                app.Window.Position(2) + app.Window.Position(4), ... y
                app.Window.Position(3), ... w
                3 ... h
                ]);
            app.X_Slider.Limits(2) = w;
            app.X_Spinner.Limits(2) = w;
            
            app.Y_Slider.Position = floor([...
                app.Window.Position(1), ... x
                app.Window.Position(2), ... y
                3, ... w
                app.Window.Position(4)... h
                ]);
            app.Y_Slider.Limits(2) = h;
            app.Y_Spinner.Limits(2) = h;
            for n = 1:length(app.StimuliNode.Children)
                node = app.StimuliNode.Children(n);
                node.NodeData.Pos.x = min(node.NodeData.Pos.x, w-1);
                node.NodeData.Pos.y = min(node.NodeData.Pos.y, h-1);
                StimPos(app, node, ...
                    node.NodeData.Pos.x, ...
                    node.NodeData.Pos.y, ...
                    node.NodeData.Pos.s);
            end
           
        end
        
        function StimPos(app, stim, x, y, s)
            dim = size(stim.NodeData.Img);
            
            stim.NodeData.Pos.x = x;
            stim.NodeData.Rect.XData = [x - dim(1)*s/2, x + dim(1)*s/2];
            app.X_Slider.Value = x;
            app.X_Spinner.Value = x;
            
            stim.NodeData.Pos.y = y;
            stim.NodeData.Rect.YData = [y - dim(2)*s/2, y + dim(2)*s/2];
            app.Y_Slider.Value = y;
            app.Y_Spinner.Value = y;
            
            app.Scale_Spinner.Value = s;
            stim.NodeData.Pos.s = s;
        end
        
        function el = saveobj(app)            
            empty = [isempty(app.StimuliNode.Children), isempty(app.ResponsesNode.Children), isempty(app.CodeNode.Children)];
            nodes = {[app.StimuliNode], [app.ResponsesNode], [app.CodeNode]};
            nodes(empty) = {struct('Children', struct('NodeData', [NaN]))};
            
            el = struct(...
                'ElementName', app.ElementName.Value, ...
                'Stimuli', [nodes{1}.Children.NodeData], ...
                'Dur', app.DurationSpinner.Value, ...
                'Measures', [nodes{2}.Children.NodeData], ...
                'Coding', [nodes{3}.Children.NodeData], ...
                'Colour', [app.RedSpinner.Value, app.GreenSpinner.Value, app.BlueSpinner.Value] ...
                );
            
            save(['Elements\' app.ElementName.Value '.mat'], 'el')
        end
        
        function loadobj(app, el)
            app.ElementName.Value = el.ElementName;
            app.DurationSpinner.Value = el.Dur;
            
            for n = 1:length(el.Stimuli)
                if isstruct(el.Stimuli)
                    stim = uitreenode(app.StimuliNode, ...
                        'Text', el.Stimuli(n).Name, ...
                        'NodeData', struct('Name', el.Stimuli.Name, ...
                        'Order', el.Stimuli(n).Order, ...
                        'Pos', el.Stimuli(n).Pos, ...
                        'Dir', el.Stimuli(n).Dir, ...
                        'Img', el.Stimuli(n).Img), ...
                        'Tag', 'Stim' ...
                        );
                    
                    stim.NodeData.Rect = rectangle(app.Window, ...
                        'Position',[...
                        el.Stimuli(n).Pos.x, ... x
                        el.Stimuli(n).Pos.y, ... y
                        el.Stimuli(n).Img.Dim(1) * el.Stimuli.Pos.s, ... w
                        el.Stimuli(n).Img.Dim(2) * el.Stimuli.Pos.s ... h
                        ], ...
                        'FaceColor',[0,33,71]./255, ...
                        'EdgeColor',[0,33,71]./255);
                end
            end
            
            for n = 1:length(el.Measures)
                if isstruct(el.Measures)
                    uitreenode(app.ResponsesNode, ...
                        'Text', el.Measures(n).Name, ...
                        'NodeData', struct(...
                        'Name', el.Measures(n).Name, ...
                        'Mapping', el.Measures(n).Mapping), ...
                        'Tag', 'Meas' ...
                        );
                end
            end
            
            app.RedSpinner.Value = el.Colour(1);
            app.GreenSpinner.Value = el.Colour(3);
            app.BlueSpinner.Value = el.Colour(2);
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center');
            app.UIFigure.Visible = 'on';
            
            app.TabGroup.SelectedTab = app.NoneTab;
            load([app.rootdir, 'Design\Screen.mat'], '-mat', 'dim');
            ScreenSz(app, dim(1), dim(2));
        end

        % Button pushed function: StimulusButton
        function NewStimulusNode(app, event)
            stim = uitreenode(app.StimuliNode, ...
                'Text', 'Stimulus...', ...
                'NodeData', struct( ...
                'Order', {'Constant'}, ...
                'Pos', struct('x', [1], 'y', [1], 's', [1]), ...
                'Dir', 'NA', ...
                'Img', struct('Px', [], 'Map', [], 'A', []), ...
                'Rect', [] ...
                ), ...
                'Tag', 'Stim' ...
                );

            expand(app.StimuliNode)
            app.Tree.SelectedNodes = stim;            
            event.Source.Parent.Visible = 'off';
        end

        % Callback function: X_Slider, X_Slider, X_Spinner, X_Spinner
        function X_Changed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ...
                event.Value, ...
                app.Tree.SelectedNodes.NodeData.Pos.y, ...
                app.Tree.SelectedNodes.NodeData.Pos.s);
        end

        % Callback function: Y_Slider, Y_Slider, Y_Spinner, Y_Spinner
        function Y_Changed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ...
                app.Tree.SelectedNodes.NodeData.Pos.x, ...
                event.Value, ...
                app.Tree.SelectedNodes.NodeData.Pos.s);
        end

        % Callback function: Scale_Spinner, Scale_Spinner
        function S_Changed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ...
                app.Tree.SelectedNodes.NodeData.Pos.x, ...
                app.Tree.SelectedNodes.NodeData.Pos.y, ...
                event.Value);
        end

        % Button pushed function: X_CenterButton
        function X_CenterButtonPushed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ...
                app.ScreenWidth.Value/2, ...
                app.Tree.SelectedNodes.NodeData.Pos.y, ...
                app.Tree.SelectedNodes.NodeData.Pos.s);
        end

        % Button pushed function: Y_CenterButton
        function Y_CenterButtonPushed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ...
                app.Tree.SelectedNodes.NodeData.Pos.x, ...
                app.ScreenHeight.Value/2, ...
                app.Tree.SelectedNodes.NodeData.Pos.s);
        end

        % Callback function: BlueSpinner, BlueSpinner, GreenSpinner, 
        % GreenSpinner, RedSpinner, RedSpinner
        function Bg_Changed(app, event)
            spinners = [app.RedSpinner, app.GreenSpinner, app.BlueSpinner];
            app.Window.BackgroundColor(spinners == event.Source) = event.Value;
        end

        % Value changed function: NewButton
        function OpenNewInterface(app, event)
            app.AddNodePanel.Visible = event.Value

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            switch app.Tree.SelectedNodes.Tag
                case 'Stim'
                    loadStim(app)
                    
                    app.X_Slider.Visible = 'on';
                    app.Y_Slider.Visible = 'on';
                    app.TabGroup.SelectedTab = app.ImageTab;
                    
                    app.StimName.Value = event.SelectedNodes.NodeData.Name;
                    app.StimOrder.Value = event.SelectedNodes.NodeData.Order;
                    app.X_Spinner.Value = event.SelectedNodes.NodeData.Pos.x;
                    app.Y_Spinner.Value = event.SelectedNodes.NodeData.Pos.y;
                    app.Scale_Spinner.Value = event.SelectedNodes.NodeData.Pos.s;
                    app.FileName.Value = event.SelectedNodes.NodeData.Dir;
                    app.ImgDimLab.Text = [num2str(event.SelectedNodes.NodeData.Img.Dim(1)) ' x ' num2str(event.SelectedNodes.NodeData.Img.Dim(2))];
                    im = imshow(event.SelectedNodes.NodeData.Img.Px, ...
                        event.SelectedNodes.NodeData.Img.Map, ...
                        'Parent', app.StimPreview);
                    im.AlphaData = event.SelectedNodes.NodeData.Img.A;
                    
                    StimPos(app, app.Tree.SelectedNodes, ...
                        app.Tree.SelectedNodes.NodeData.Pos.x, ...
                        app.Tree.SelectedNodes.NodeData.Pos.y, ...
                        app.Tree.SelectedNodes.NodeData.Pos.s);
                    
                    event.SelectedNodes.NodeData.Rect.FaceColor = [0.90,0.00,0.49];
                    event.SelectedNodes.NodeData.Rect.EdgeColor = [0.90,0.00,0.49];
                    try
                        event.PreviousSelectedNodes.NodeData.Rect.FaceColor = [0 0.1294 0.2784];
                        event.PreviousSelectedNodes.NodeData.Rect.EdgeColor = [0 0.1294 0.2784];
                    end
                case 'Meas'
                    app.TabGroup.SelectedTab = app.KBRespTab;
                    app.X_Slider.Visible = 'off';
                    app.Y_Slider.Visible = 'off';
                    
                    app.ButtonCoding.Data = app.Tree.SelectedNodes.NodeData.Mapping;
                    app.MeasureName.Value = app.Tree.SelectedNodes.NodeData.Name;
                case 'Code'
                    app.TabGroup.SelectedTab = app.CodeTab;
                    app.X_Slider.Visible = 'off';
                    app.Y_Slider.Visible = 'off';
                    
                    app.CodingName.Value = app.Tree.SelectedNodes.NodeData.Name;
                    app.CodeTextArea.Value = app.Tree.SelectedNodes.NodeData.Code;
                    
                otherwise
                    app.TabGroup.SelectedTab = app.NoneTab;
            end
        end

        % Button pushed function: DeleteStimButton
        function DeleteStimButtonPushed(app, event)
            delete(app.Tree.SelectedNodes.NodeData.Rect)
            delete(app.Tree.SelectedNodes)
            app.Tree.SelectedNodes = app.StimuliNode;
            TreeSelectionChanged(app, app.Tree);
        end

        % Callback function: StimFileSelection, Tree2
        function StimSelectionChanged(app, event)
            [img.Px, img.Map, img.A] = imread(event.SelectedNodes.NodeData);
            dim = size(img.Px);
            app.ImgDimLab.Text = [num2str(dim(1)) ' x ' num2str(dim(2))];
            
            app.Tree.SelectedNodes.NodeData.Dir = event.SelectedNodes.NodeData;
            app.Tree.SelectedNodes.NodeData.Img = img;
            if ~isempty(app.Tree.SelectedNodes.NodeData.Rect)
                delete(app.Tree.SelectedNodes.NodeData.Rect)
            end
            
            app.Tree.SelectedNodes.NodeData.Rect = imshow(img.Px, ...
                img.Map, ...
                'Parent', app.Window);
           if ~isempty(img.A)
               app.Tree.SelectedNodes.NodeData.Rect.AlphaData = img.A;
           end
            
            StimPos(app, app.Tree.SelectedNodes, ...
                app.Window.XLim(2)/2, ...
                app.Window.YLim(2)/2, ...
                1);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.TabGroup.SelectedTab = event.OldValue; %prevent changing tabs manually

        end

        % Callback function
        function EditFieldValueChanged(app, event)
            app.Tree.SelectedNodes.Text = event.Value;
        end

        % Value changed function: NoResponseLimit
        function NoResponseLimitValueChanged(app, event)
            if event.Value == true
                app.DurationSpinner.Enable = false;
                app.DurationSpinner.Value = Inf;
            else
                app.DurationSpinner.Enable = true;
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveobj(app);
            delete(app);
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
            
        end

        % Button pushed function: NewButtonMap
        function NewButtonMapPushed(app, event)
            app.NewButtonCoding.Data = [app.ButtonCoding.Data; {'Button'} {'Code'}];
            app.Tree.SelectedNodes.NodeData.Mapping = [app.Tree.SelectedNodes.NodeData.Mapping; {'NewButton'} {'Code'}];
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            meas = uitreenode(app.ResponsesNode, ...
                'Text', 'Measure...', ...
                'NodeData', struct(...
                'Name', 'Measure Name', ...
                'Mapping', {{'FactorsButton', 'Code'}}), ...
                'Tag', 'Meas' ...
                );
            app.Tree.SelectedNodes = meas;
            TreeSelectionChanged(app, app.Tree);
            app.AddNodePanel.Visible = false;
            expand(app.ResponsesNode)
        end

        % Button pushed function: DeleteMeasure
        function DeleteMeasurePushed(app, event)
            delete(app.Tree.SelectedNodes);
            
        end

        % Cell edit callback: ButtonCoding
        function ButtonCodingCellEdit(app, event)
            adjacent = app.ButtonCoding.Data{event.Indices(1),3-event.Indices(2)};
            if isempty(event.NewData) && isempty(adjacent)
                app.ButtonCoding.Data(event.Indices(1),:) = [];
                app.Tree.SelectedNodes.NodeData.Mapping(event.Indices(1),:) = [];
            else
                app.Tree.SelectedNodes.NodeData.Mapping(event.Indices(1), event.Indices(2)) = {event.NewData};
            end
            
        end

        % Callback function: MeasureName, MeasureName
        function EditField_2ValueChanged(app, event)
            app.Tree.SelectedNodes.Text = event.Value;
        end

        % Value changed function: CodeTextArea
        function CodeTextAreaValueChanged(app, event)
            app.Tree.SelectedNodes.NodeData.Code = event.Value;

        end

        % Callback function: CodingName, CodingName
        function CodingNameValueChanged(app, event)
            app.Tree.SelectedNodes.NodeData.Name = event.Value;
            app.Tree.SelectedNodes.Text = event.Value;
            
        end

        % Button pushed function: CodeButton
        function CodeButtonPushed(app, event)
            co = uitreenode(app.CodeNode, ...
                'Text', 'Code Component Name', ...
                'NodeData', struct(...
                'Name', 'Code Component Name', ...
                'Code', ''), ...
                'Tag', 'Code' ...
                );
            app.Tree.SelectedNodes = co;
            TreeSelectionChanged(app, app.Tree);
            app.AddNodePanel.Visible = false;
            expand(app.CodeNode)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Window = uiaxes(app.UIFigure);
            app.Window.XLim = [1 750];
            app.Window.YLim = [1 500];
            app.Window.XTick = [];
            app.Window.YTick = [];
            app.Window.Visible = 'off';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.StimuliNode = uitreenode(app.Tree);
            app.StimuliNode.Text = 'Stimuli';
            app.ResponsesNode = uitreenode(app.Tree);
            app.ResponsesNode.Text = 'Responses';
            app.CodeNode = uitreenode(app.Tree);
            app.CodeNode.Text = 'Code';
            app.ElementName = uieditfield(app.UIFigure, 'text');
            app.ElementName.Value = 'Element Name';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.PresentationOrderLabel = uilabel(app.ImageTab);
            app.PresentationOrderLabel.Text = 'Presentation Order:';
            app.StimOrder = uidropdown(app.ImageTab);
            app.StimOrder.Items = {'Constant'};
            app.StimOrder.Value = 'Constant';
            app.DeleteStimButton = uibutton(app.ImageTab, 'push');
            app.DeleteStimButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteStimButtonPushed, true);
            app.DeleteStimButton.Text = 'Delete';
            app.ImgDimLab = uilabel(app.ImageTab);
            app.ImgDimLab.Text = '';
            app.Tree2 = uitree(app.ImageTab);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @StimSelectionChanged, true);
            app.StimuliLabel = uilabel(app.ImageTab);
            app.StimuliLabel.Text = 'Stimuli:';
            app.PositionLabel = uilabel(app.ImageTab);
            app.PositionLabel.Text = 'Position';
            app.X_SpinnerLabel = uilabel(app.ImageTab);
            app.X_SpinnerLabel.Text = 'X:';
            app.X_Spinner = uispinner(app.ImageTab);
            app.X_Spinner.ValueChangingFcn = createCallbackFcn(app, @X_Changed, true);
            app.X_Spinner.Limits = [1 750];
            app.X_Spinner.ValueChangedFcn = createCallbackFcn(app, @X_Changed, true);
            app.X_Spinner.Value = 1;
            app.X_CenterButton = uibutton(app.ImageTab, 'push');
            app.X_CenterButton.ButtonPushedFcn = createCallbackFcn(app, @X_CenterButtonPushed, true);
            app.X_CenterButton.Text = 'Center';
            app.Y_SpinnerLabel = uilabel(app.ImageTab);
            app.Y_SpinnerLabel.Text = 'Y:';
            app.Y_Spinner = uispinner(app.ImageTab);
            app.Y_Spinner.ValueChangingFcn = createCallbackFcn(app, @Y_Changed, true);
            app.Y_Spinner.Limits = [1 500];
            app.Y_Spinner.ValueChangedFcn = createCallbackFcn(app, @Y_Changed, true);
            app.Y_Spinner.Value = 1;
            app.Y_CenterButton = uibutton(app.ImageTab, 'push');
            app.Y_CenterButton.ButtonPushedFcn = createCallbackFcn(app, @Y_CenterButtonPushed, true);
            app.Y_CenterButton.Text = 'Center';
            app.XSpinnerLabel_3 = uilabel(app.ImageTab);
            app.XSpinnerLabel_3.Text = 'Scale:';
            app.Scale_Spinner = uispinner(app.ImageTab);
            app.Scale_Spinner.Step = 0.25;
            app.Scale_Spinner.ValueChangingFcn = createCallbackFcn(app, @S_Changed, true);
            app.Scale_Spinner.Limits = [0 Inf];
            app.Scale_Spinner.ValueChangedFcn = createCallbackFcn(app, @S_Changed, true);
            app.Scale_Spinner.Value = 1;
            app.KBRespTab = uitab(app.TabGroup);
            app.KBRespTab.Title = 'KB Resp';
            app.MeasureName = uieditfield(app.KBRespTab, 'text');
            app.MeasureName.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.MeasureName.ValueChangingFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.MeasureName.Value = 'Measure Name';
            app.ButtonCoding = uitable(app.KBRespTab);
            app.ButtonCoding.ColumnName = {'If they press...'; 'Record it as...'};
            app.ButtonCoding.RowName = {};
            app.ButtonCoding.ColumnEditable = true;
            app.ButtonCoding.CellEditCallback = createCallbackFcn(app, @ButtonCodingCellEdit, true);
            app.NewButtonMap = uibutton(app.KBRespTab, 'push');
            app.NewButtonMap.ButtonPushedFcn = createCallbackFcn(app, @NewButtonMapPushed, true);
            app.NewButtonMap.Text = 'Add Map';
            app.DeleteMeasure = uibutton(app.KBRespTab, 'push');
            app.DeleteMeasure.ButtonPushedFcn = createCallbackFcn(app, @DeleteMeasurePushed, true);
            app.DeleteMeasure.Text = 'Delete';
            app.EndroutineonresponseCheckBox = uicheckbox(app.KBRespTab);
            app.EndroutineonresponseCheckBox.Text = 'End routine on response';
            app.CodeTab = uitab(app.TabGroup);
            app.CodeTab.Title = 'Code';
            app.DeleteCode = uibutton(app.CodeTab, 'push');
            app.DeleteCode.Text = 'Delete';
            app.CodeTextAreaLabel = uilabel(app.CodeTab);
            app.CodeTextAreaLabel.Text = 'Code:';
            app.CodeTextArea = uitextarea(app.CodeTab);
            app.CodeTextArea.ValueChangedFcn = createCallbackFcn(app, @CodeTextAreaValueChanged, true);
            app.CodingName = uieditfield(app.CodeTab, 'text');
            app.CodingName.ValueChangedFcn = createCallbackFcn(app, @CodingNameValueChanged, true);
            app.CodingName.ValueChangingFcn = createCallbackFcn(app, @CodingNameValueChanged, true);
            app.CodingName.Value = 'Code Component Name';
            app.EdulogTab = uitab(app.TabGroup);
            app.EdulogTab.Title = 'Edulog';
            app.LoggersButtonGroup = uibuttongroup(app.EdulogTab);
            app.LoggersButtonGroup.Title = 'Loggers';
            app.TemperatureButton = uiradiobutton(app.LoggersButtonGroup);
            app.TemperatureButton.Text = 'Temperature';
            app.TemperatureButton.Value = true;
            app.LightButton = uiradiobutton(app.LoggersButtonGroup);
            app.LightButton.Text = 'Light';
            app.PulseButton = uiradiobutton(app.LoggersButtonGroup);
            app.PulseButton.Text = 'Pulse';
            app.SoundButton = uiradiobutton(app.LoggersButtonGroup);
            app.SoundButton.Text = 'Sound';
            app.HumidityButton = uiradiobutton(app.LoggersButtonGroup);
            app.HumidityButton.Text = 'Humidity';
            app.MotionButton = uiradiobutton(app.LoggersButtonGroup);
            app.MotionButton.Text = 'Motion';
            app.BloodPressureButton = uiradiobutton(app.LoggersButtonGroup);
            app.BloodPressureButton.Text = 'BloodPressure';
            app.HandDynamometerButton = uiradiobutton(app.LoggersButtonGroup);
            app.HandDynamometerButton.Text = 'HandDynamometer';
            app.GSRButton = uiradiobutton(app.LoggersButtonGroup);
            app.GSRButton.Text = 'GSR';
            app.EKGButton = uiradiobutton(app.LoggersButtonGroup);
            app.EKGButton.Text = 'EKG';
            app.PortEditFieldLabel = uilabel(app.EdulogTab);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.EdulogTab, 'text');
            app.SamplesPerSecondSpinnerLabel = uilabel(app.EdulogTab);
            app.SamplesPerSecondSpinnerLabel.Text = 'Samples Per Second';
            app.SamplesPerSecondSpinner = uispinner(app.EdulogTab);
            app.SamplesPerSecondSpinner.Step = 0.5;
            app.SamplesPerSecondSpinner.Limits = [0.5 5];
            app.SamplesPerSecondSpinner.Value = 0.5;
            app.CodingName_2 = uieditfield(app.EdulogTab, 'text');
            app.CodingName_2.Value = 'Edulog Measure Name';
            app.NoneTab = uitab(app.TabGroup);
            app.NoneTab.Title = ' ';
            app.X_Slider = uislider(app.UIFigure);
            app.X_Slider.Limits = [1 750];
            app.X_Slider.MajorTicks = [];
            app.X_Slider.ValueChangedFcn = createCallbackFcn(app, @X_Changed, true);
            app.X_Slider.ValueChangingFcn = createCallbackFcn(app, @X_Changed, true);
            app.X_Slider.MinorTicks = [];
            app.X_Slider.Visible = 'off';
            app.X_Slider.Value = 375;
            app.Y_Slider = uislider(app.UIFigure);
            app.Y_Slider.Limits = [1 500];
            app.Y_Slider.MajorTicks = [];
            app.Y_Slider.ValueChangedFcn = createCallbackFcn(app, @Y_Changed, true);
            app.Y_Slider.ValueChangingFcn = createCallbackFcn(app, @Y_Changed, true);
            app.Y_Slider.MinorTicks = [];
            app.Y_Slider.Visible = 'off';
            app.Y_Slider.Value = 250;
            app.AddNodePanel = uipanel(app.UIFigure);
            app.AddNodePanel.Visible = 'off';
            app.StimulusButton = uibutton(app.AddNodePanel, 'push');
            app.StimulusButton.ButtonPushedFcn = createCallbackFcn(app, @NewStimulusNode, true);
            app.StimulusButton.Text = 'Stimulus';
            app.CodeButton = uibutton(app.AddNodePanel, 'push');
            app.CodeButton.ButtonPushedFcn = createCallbackFcn(app, @CodeButtonPushed, true);
            app.CodeButton.Text = 'Code';
            app.MeasureButton = uibutton(app.AddNodePanel, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.DurationmsLabel = uilabel(app.UIFigure);
            app.DurationmsLabel.Text = 'Duration (ms):';
            app.DurationSpinner = uispinner(app.UIFigure);
            app.DurationSpinner.Step = 100;
            app.DurationSpinner.RoundFractionalValues = 'on';
            app.DurationSpinner.Value = 100;
            app.NoResponseLimit = uicheckbox(app.UIFigure);
            app.NoResponseLimit.ValueChangedFcn = createCallbackFcn(app, @NoResponseLimitValueChanged, true);
            app.NoResponseLimit.Text = 'No Limit';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.BlueSpinnerLabel = uilabel(app.UIFigure);
            app.BlueSpinnerLabel.Text = 'Blue';
            app.BlueSpinner = uispinner(app.UIFigure);
            app.BlueSpinner.Step = 0.01;
            app.BlueSpinner.ValueChangingFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.BlueSpinner.Limits = [0 1];
            app.BlueSpinner.ValueChangedFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.BlueSpinner.Value = 0.5;
            app.GreenSpinnerLabel = uilabel(app.UIFigure);
            app.GreenSpinnerLabel.Text = 'Green';
            app.GreenSpinner = uispinner(app.UIFigure);
            app.GreenSpinner.Step = 0.01;
            app.GreenSpinner.ValueChangingFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.GreenSpinner.Limits = [0 1];
            app.GreenSpinner.ValueChangedFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.GreenSpinner.Value = 0.5;
            app.RedSpinnerLabel = uilabel(app.UIFigure);
            app.RedSpinnerLabel.Text = 'Red';
            app.RedSpinner = uispinner(app.UIFigure);
            app.RedSpinner.Step = 0.01;
            app.RedSpinner.ValueChangingFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.RedSpinner.Limits = [0 1];
            app.RedSpinner.ValueChangedFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.RedSpinner.Value = 0.5;
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = 'File:';
            app.FileName = uieditfield(app.UIFigure, 'text');
            app.FileName.Editable = 'off';
            app.FileName.Enable = 'off';
            app.StimFileSelection = uibutton(app.UIFigure, 'push');
            app.StimFileSelection.ButtonPushedFcn = createCallbackFcn(app, @StimSelectionChanged, true);
            app.StimFileSelection.Text = 'Browse';
            app.NewButton = uibutton(app.UIFigure, 'state');
            app.NewButton.ValueChangedFcn = createCallbackFcn(app, @OpenNewInterface, true);
            app.NewButton.Text = 'New';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Element

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end