classdef Pedestal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewExperimentMenu           matlab.ui.container.Menu
        OpenExperimentMenu          matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        SaveAsMenu                  matlab.ui.container.Menu
        CloseMenu                   matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        ComponentsMenu              matlab.ui.container.Menu
        StimuliMenu                 matlab.ui.container.Menu
        NewStimulusMenu             matlab.ui.container.Menu
        ElementsMenu                matlab.ui.container.Menu
        NewElementMenu              matlab.ui.container.Menu
        ImportElementMenu           matlab.ui.container.Menu
        RoutinesMenu                matlab.ui.container.Menu
        NewRoutineMenu              matlab.ui.container.Menu
        ImportRoutineMenu           matlab.ui.container.Menu
        EditDesignMenu              matlab.ui.container.Menu
        EditProcedureMenu           matlab.ui.container.Menu
        ExperimentMenu              matlab.ui.container.Menu
        RunMenu                     matlab.ui.container.Menu
        StopMenu                    matlab.ui.container.Menu
        CompileMenu                 matlab.ui.container.Menu
        DesignTree                  matlab.ui.container.Tree
        WithinSubjectsFactorsNode   matlab.ui.container.TreeNode
        BetweenSubjectsFactorsNode  matlab.ui.container.TreeNode
        MeasuresNode                matlab.ui.container.TreeNode
        Node                        matlab.ui.container.TreeNode
        EditDesignButton            matlab.ui.control.Button
        ElementName                 matlab.ui.control.EditField
        ElementTree_2               matlab.ui.container.Tree
        ManageDataButton            matlab.ui.control.Button
        DesignLabel                 matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        ProcedureTree               matlab.ui.container.Tree
        PTBlank                     matlab.ui.container.TreeNode
        ProcedureLabel              matlab.ui.control.Label
        EditProcedureButton         matlab.ui.control.Button
        ComponentPanel              matlab.ui.container.Panel
        ComponentManagerLabel       matlab.ui.control.Label
        ComponentViewer             matlab.ui.container.TabGroup
        StimulusTab                 matlab.ui.container.Tab
        StimPreview                 matlab.ui.control.UIAxes
        DeleteButton_2              matlab.ui.control.Button
        ElementTab                  matlab.ui.container.Tab
        DeleteButton                matlab.ui.control.Button
        EditButton                  matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        BgPreview                   matlab.ui.control.UIAxes
        BackgroundColourLabel       matlab.ui.control.Label
        ContentsLabel               matlab.ui.control.Label
        ElementNameLabel            matlab.ui.control.Label
        DurationLabel               matlab.ui.control.Label
        RGBLabel                    matlab.ui.control.Label
        RoutineTab                  matlab.ui.container.Tab
        RoutineNameLabel            matlab.ui.control.Label
        RoutineDuration             matlab.ui.control.Label
        ElementsLabel               matlab.ui.control.Label
        RoutineElements             matlab.ui.container.Tree
        ElDurLabel                  matlab.ui.control.Label
        FactorsLabel                matlab.ui.control.Label
        RoutineFactors              matlab.ui.control.Table
        DeleteButton_3              matlab.ui.control.Button
        EditButton_2                matlab.ui.control.Button
        NoneTab                     matlab.ui.container.Tab
        ComponentTree               matlab.ui.container.Tree
        StimuliNode                 matlab.ui.container.TreeNode
        ElementsNode                matlab.ui.container.TreeNode
        RoutinesNode                matlab.ui.container.TreeNode
        cmpBlank                    matlab.ui.container.TreeNode
        AddComponentPanel           matlab.ui.container.Panel
        StimulusButton              matlab.ui.control.Button
        ElementButton               matlab.ui.control.Button
        RoutineButton               matlab.ui.control.Button
        NewComponentButton          matlab.ui.control.StateButton
        RefreshButton               matlab.ui.control.Button
        DataLabel                   matlab.ui.control.Label
        ActionDetails               matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        rootdir % Location of root experiment folder
    end
    
    methods (Access = public)        
        function loadproc(app, file)
            % Function to load saved procedure
            % file = location of saved procedure file
            
            load(file, '-mat', 'pr') % Load procedure structure
            delete(app.ProcedureTree.Children(app.ProcedureTree.Children ~= app.PTBlank)); % Delete all nodes apart from the blank placeholder
            for node = pr' % For each node in pr...
                parent = uitreenode(app.ProcedureTree, app.PTBlank, 'before', ... % Recreate it as a new node in the procedure tree
                    'Text', node.Text, ...
                    'Tag', node.Tag, ...
                    'NodeData', node.NodeData ...
                    );
                switch parent.Tag
                    case {'For', 'If', 'Else', 'While', 'Random'} % If parent is a container node..
                        procCycle(app, node.Children, parent); % Run recursive function to generate children
                    case 'R' % If parent is a routine node...
                        for e = 1:length(node.NodeData.Elements) % For each element in the routine...
                            el = node.NodeData.Elements(e); % Extract element
                            uitreenode(parent, ... % Recreate element as a new node in the procedure tree
                                'Text', el.el.ElementName, ...
                                'Tag', 'El', ...
                                'NodeData', el ...
                                );
                        end
                end
            end
        end
        function procCycle(app, children, parent)
            % Recursive function to tunnel into a container node and recreate child nodes within it
            % Children = structure of child nodes from saved experiment
            % Parent = newly generated parent node to recreate child nodes in
            
            for ch = children' % For each child node...
                child = uitreenode(parent, ... % Recreate it as a new node in the procedure tree
                    'Text', ch.Text, ...
                    'Tag', ch.Tag, ...
                    'NodeData', ch.NodeData ...
                    );
                switch child.Tag
                    case {'For', 'If', 'Else', 'While', 'Random'} % If it's a container node...
                        procCycle(app, ch.Children, child); % Recurse
                    case 'R' % If it's a routine node...
                        for el = ch.NodeData.Elements' % For each of its elements...
                            uitreenode(child, ... % Reacreate it as a new node under the routine node
                                'Text', el.el.ElementName, ...
                                'Tag', 'El', ...
                                'NodeData', el ...
                                );
                        end
                end
            end
        end
        
        function loaddes(app, file)
            load(file, '-mat', 'des');
            
            %% load BS factors
            delete(app.BetweenSubjectsFactorsNode.Children) % Delete previous between subjects factor nodes
            for bsf = des.BS_Factors' % For each between subjects factor...
                factor = uitreenode(app.BetweenSubjectsFactorsNode,  ... % Replicate it as a new node under the between subjects node
                    'Text', bsf.Name, ...
                    'NodeData', bsf.Levels, ...
                    'Tag', 'B' ...
                    );
                for ch = factor.NodeData' % For each level...
                    uitreenode(factor,  ... % Recreate it as a new node under its factor node
                    'Text', factor.Text, ...
                    'Tag', factor.Tag ...
                    );
                end
            end
            expand(app.BetweenSubjectsFactorsNode); % Expand the between subjects node
            
            %% load WS factors
            delete(app.WithinSubjectsFactorsNode.Children) % Delete previous within subjects factor nodes
            for qsf = des.WS_Factors' % For each within subjects factor...
                factor = uitreenode(app.BetweenSubjectsFactorsNode,  ... % Replicate it as a new node under the within subjects node
                    'Text', qsf.Name, ...
                    'NodeData', qsf.Levels, ...
                    'Tag', 'W' ...
                    );
                for ch = factor.NodeData' % For each level...
                    uitreenode(factor,  ... % Recreate it as a new node under its factor node
                    'Text', factor.Text, ...
                    'Tag', factor.Tag ...
                    );
                end
            end
            expand(app.WithinSubjectsFactorsNode); % Expand the within subjects node
            
            %% load Measures
            delete(app.MeasuresNode.Children) % Delete previous measure nodes
            for ms = des.Measures' % For each measure...
                uitreenode(app.MeasuresNode,  ... % Recreate it as a new node under the measures node
                    'Text', ms.Name, ...
                    'NodeData', ms.Type, ...
                    'Tag', 'M' ...
                    );
            end
            expand(app.MeasuresNode) % Expand the measures node
        end
    
        function loadcomp(app)
            cmpTypes = app.ComponentTree.Children(app.ComponentTree.Children~=app.cmpBlank); % Store container node handles for the different component types
            
            for parent = cmpTypes' % For each component type node...
                delete(parent.Children) % Delete existing component nodes
                
                files = dir([app.rootdir, parent.NodeData]); % Get filenames of all files in stimuli folder
                files = files(~strcmp({files.name}, '..') & ~strcmp({files.name}, '.')); % Remove filenames which are just . or ..
                for cmp = files' % For each file...
                    uitreenode(parent,  ... % Create a node under the parent node
                        'Text', cmp.name, ...
                        'NodeData', [app.rootdir, 'Stimuli\', cmp.name], ...
                        'Tag', parent.Tag ...
                        );
                end
                expand(parent); % Expand parent node
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off'; % Hide window
            addpath(genpath(pwd), genpath('Experiments')); % Add all subfolders to path
            format long % Set format to long to avoid scientific notation
            
            %% Splash
            [im, map, alpha] = imread('System\Icons\Splash.png'); % Load splash image
            imsize = size(im); % Get original image size
            imsize = imsize*(1000/max(imsize)); % Transform so it fits within a 1000*1000 box
            
            splash = figure(... % Creature figure
                'Name', '', ... 
                'MenuBar', 'none', ... 
                'NumberTitle', 'off', ... 
                'Position', [200,200,imsize(2),imsize(1)] ... 
                );
            movegui(splash,'center'); % Move figure to centre
            splaxis = axes(splash, ... % Create axis within figure
                'Units', 'normalized', ...
                'Position', [0 0 1 1] ...
                );
            image(splaxis, im); % Plot splash image on axis
            splaxis.TickLength = [0 0]; % Remove axis ticks
            %%
            
            pause(2)
            

            delete(splash) % Close splash
            set(0,'units','pixels'); % Set units to pixels
            app.UIFigure.Position = get(groot, 'Screensize') + [99, 99, -200, -200]; % Make window occupy most of the screen
            movegui(app.UIFigure,'center') % Center window
            app.ComponentViewer.SelectedTab = app.NoneTab; % Switch to blank tab in component viewer
            
            app.UIFigure.Visible = 'on'; % Reveal window
            
        end

        % Callback function: EditProcedureButton, EditProcedureMenu
        function EditProcedure(app, event)
            load([app.rootdir, '\Design\Procedure.mat']) % Load procedure file from save
            p = Procedure; % Open Procedure interface
            p.loadobj(pr) % Load procedure to interface
            p.rootdir = app.rootdir; % Pass root directory
            p.Parent = app; % Pass parent handle
            
        end

        % Selection changed function: ProcedureTree
        function ProcedureTreeSelectionChanged(app, event)
            node = event.SelectedNodes;
            
            switch node.Tag
                case {'If', 'Else'} % If selection is an If or Else node
                    app.ActionDetails.Value = [... % Present following text to action details panel:
                        'If ' node.NodeData.IfDropDown ...
                        ' is ' node.NodeData.IfIsDropDown ...
                        ' ' node.NodeData.IfComparedToField '...'];
                    
                case 'While'
                    app.ActionDetails.Value = [... % Present following text to action details panel:
                        'Reapeat until ' node.NodeData.WhileSubject ...
                        ' is ' node.NodeData.WhileIsDropDown ...
                        ' ' node.NodeData.WhileComparedToField ...
                        ', each time adding ' num2str(node.NodeData.WhileIntervalSpinner) ...
                        ' to the variable ' node.NodeData.WhileIteratorField ...
                        ' starting at ' num2str(node.NodeData.WhileStartSpinner)];
                    
                case 'For'
                    app.ActionDetails.Value = [... % Present following text to action details panel:
                        'Repeat ' num2str(node.NodeData.ForRepeatsSpinner) ' times,' ...
                        ' each time adding ' num2str(node.NodeData.ForIntervalSpinner) ...
                        ' to the variable "' node.NodeData.ForIteratorField '",' ...
                        ' starting at ' num2str(node.NodeData.ForStartSpinner)...
                        ];
                    
                case 'Random'
                    if node.NodeData.RandFullButton
                        randType = ' fully';
                    elseif node.NodeData.RandEvenButton
                        randType = 'n evenly';
                    end
                    
                    app.ActionDetails.Value = [... % Present following text to action details panel:
                        'Run ' num2str(node.NodeData.RandSpinner) 'of the contained components'...
                        ', in a' randType ' random order' ...
                        ];
                    
                case 'R'
                    app.ActionDetails.Value = [... % Present following text to action details panel:
                        node.NodeData.Name ': ' ...
                        num2str(node.NodeData.Dur) ' ms, ' ...
                        num2str(length({node.NodeData.Elements})) ' Elements' ...
                        ];
                    
                case 'Code'
                    app.ActionDetails.Value = [ ... % Present following text to action details panel:
                        num2str(length(node.NodeData.Code)), ' lines of code' ...
                        ];
                    
                case 'Variable'
                    app.ActionDetails.Value = [... % Present following text to action details panel:
                        'Set ' node.NodeData.Variable ...
                        ' to equal ' node.NodeData.Value ...
                        ];
                    
                otherwise
                    app.ActionDetails.Value = ''; % Make the action details panel blank
                    
            end
        end

        % Menu selected function: NewExperimentMenu
        function NewExperiment(app, event)
            [file,path] = uiputfile('.m', 'Choose save location'); % Ask participant where to save experiment
            name = strsplit(file, '.m'); name = name{1}; % Remove file extension
            app.rootdir = [path, name]; % Store new root directory
            
            % Create folders to store experiment in
            mkdir(app.rootdir);
            mkdir(app.rootdir, 'Design');
            mkdir(app.rootdir, 'Elements');
            mkdir(app.rootdir, 'Routines');
            mkdir(app.rootdir, 'Stimuli');
            mkdir(app.rootdir, 'Data');
            
            save(file, 'app.rootdir'); % Create file pointing to root directory
            
            de = Design; % Open design interface
            de.rootdir = app.rootdir; % Pass root directory
            
            % Try to load parameters, in case participant has added files manually, otherwise create blank placeholders
            try
                loaddes(app, [app.rootdir, 'Design\Design.mat'])
            catch
                des = struct();
                save([app.rootdir, 'Design\Design.mat'], 'des');
            end
            try
                loadproc(app, [app.rootdir, 'Design\Procedure.mat'])
            catch
                pr = struct();
                save([app.rootdir, 'Design\Procedure.mat'], 'pr');
            end
            try
                loadcomp(app)
            catch
            end
            
            set([ ... % Enable the following objects...
                app.ElementName, ...
                app.RunButton, ...
                app.DesignLabel, ...
                app.ProcedureLabel, ...
                app.DesignTree, ...
                app.ProcedureTree, ...
                app.EditProcedureButton, ...
                app.EditDesignButton, ...
                app.ComponentTree, ...
                app.NewComponentButton, ...
                app.ComponentManagerLabel ...
                ], 'Enable', 'on');
            
        end

        % Menu selected function: OpenExperimentMenu
        function OpenExperiment(app, event)
            [name, app.rootdir] = uigetfile('.m'); % Get root directory from location of load file

            try
                loaddes(app, [app.rootdir, 'Design\Design.mat'])
            end
            try
                loadproc(app, [app.rootdir, 'Design\Procedure.mat'])
            end
            try
                loadcomp(app)
            end
            set([ ... % Enable the following objects...
                app.ElementName, ...
                app.RunButton, ...
                app.DesignLabel, ...
                app.ProcedureLabel, ...
                app.DesignTree, ...
                app.ProcedureTree, ...
                app.EditProcedureButton, ...
                app.EditDesignButton, ...
                app.ComponentTree, ...
                app.NewComponentButton, ...
                app.ComponentManagerLabel ...
                ], 'Enable', 'on');
            
            app.ElementName.Value = erase(name, '.m');
        end

        % Callback function: ElementName, SaveMenu
        function RenameExperiment(app, event)
            spl = strsplit(app.rootdir, '\'); % Split up root directory into folders
            newrt = [strjoin(spl(1:end-2), '\') '\' app.ElementName.Value '.m']; % Recombine with the experiment folder removed
            try
                movefile(app.rootdir, [subrt, '\', app.ElementName.Value]) % Rename experiment folder to ElementName value
                delete([app.rootdir app.ElementName.Value '.m']) % Delete old directory file
                app.rootdir = [subrt, '\', app.ElementName.Value]; % Store new root directory
                save([subrt, '\', app.ElementName.Value, '\' app.ElementName.Value '.m'], 'app.rootdir') % Save new directory file
            end

        end

        % Menu selected function: SaveAsMenu
        function SaveAs(app, event)
            [file,path] = uiputfile('.m', 'Choose save location'); % Ask participant where to save experiment
            name = strsplit(file, '.m'); name = name{1}; % Remove file extension
            app.rootdir = [path, name]; % Store new root directory
            
            newdir = [uigetdir(), '\'];
            name = inputdlg('Enter a new name for this copy of your experiment in the box below',"New Experiment Name",1,"NewExperiment");
            copyfile(app.rootdir, [newdir, name{:}])
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            app.ElementName.Enable = 'off';
            app.RunButton.Enable = 'off';
            app.DesignLabel.Enable = 'off';
            app.ProcedureLabel.Enable = 'off';
            app.DesignTree.Enable = 'off';
            app.ProcedureTree.Enable = 'off';
            app.EditProcedureButton.Enable = 'off';
            app.EditDesignButton.Enable = 'off';
            app.ComponentViewer.Visible = 'off';
            app.ComponentTree.Enable = 'off';
            app.NewButton.Enable = 'off';
            app.ComponentManagerLabel.Enable = 'off';

        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app)
        end

        % Callback function: ElementButton, NewElementMenu
        function NewElementMenuSelected(app, event)
            app.NewComponentButton.Value = false;
            app.AddComponentPanel.Visible = false;
            e = Element;
            e.rootdir = app.rootdir;
            e.Parent = app;
            loadcomp(app)
            
        end

        % Callback function: NewRoutineMenu, RoutineButton
        function NewRoutineMenuSelected(app, event)
            app.NewComponentButton.Value = false;
            app.AddComponentPanel.Visible = false;
            r = Routine;
            r.rootdir = app.rootdir;
            r.Parent = app;
            loadcomp(app)
        end

        % Callback function: EditDesignButton, EditDesignMenu
        function EditDesignMenuSelected(app, event)
            load([app.rootdir, '\Design\Design.mat'])
            d = Design;
            d.loadobj(des)
            d.rootdir = app.rootdir;
            d.Parent = app;
            
        end

        % Menu selected function: ImportElementMenu
        function ImportElementMenuSelected(app, event)
            [srcnm, srcdir] = uigetfile();
            copyfile([srcdir, srcnm], [app.rootdir, 'Elements\', srcnm]);
            loadcomp(app)
        end

        % Menu selected function: ImportRoutineMenu
        function ImportRoutineMenuSelected(app, event)
            [srcnm, srcdir] = uigetfile();
            copyfile([srcdir, srcnm], [app.rootdir, 'Routines\', srcnm]);
            loadcomp(app)
        end

        % Selection changed function: ComponentTree
        function ComponentTreeSelectionChanged(app, event)
            switch event.SelectedNodes.Parent
                case app.StimuliNode
                    app.ComponentViewer.SelectedTab = app.StimulusTab;
                    
                    [img.Px, img.Map, img.A] = imread(event.SelectedNodes.NodeData);
                    img.Dim = size(img.Px);
                    im = imshow(...
                        img.Px, ...
                        img.Map, ...
                        'Parent', app.StimPreview);
                    im.XData = im.XData + (im.Parent.XLim(2) - im.XData(2))/2;
                    im.YData = im.YData + (im.Parent.YLim(2) - im.YData(2))/2;
                    
                case app.ElementsNode
                    app.ComponentViewer.SelectedTab = app.ElementTab;
                    el = event.SelectedNodes.NodeData;
                    app.ElementNameLabel.Text = el.ElementName;
                    app.DurationLabel.Text = [num2str(el.Dur) ' ms'];
                    try
                        for s = 1:length({el.Stimuli.Dir})
                            stim = strsplit(el.Stimuli(s).Dir, '\');
                            app.UITable.Data{s,1} = stim{end};
                        end
                    end
                    try
                        for m = 1:length({el.Measures.Dir})
                            meas = strsplit(el.Measures(m).Measures, '\');
                            app.UITable.Data{m,1} = meas{end};
                        end
                    end
                    try
                        for s = 1:length({el.Coding.Name})
                            code = strsplit(el.Coding(s).Name, '\');
                            app.UITable.Data{s,1} = code{end};
                        end
                    end
                    app.RGBLabel.Text = ['[' num2str(el.Colour(1)) ', ' num2str(el.Colour(2)) ', ' num2str(el.Colour(1)) ']'];
                    app.BgPreview.BackgroundColor = el.Colour;
                    
                case app.RoutinesNode
                    app.ComponentViewer.SelectedTab = app.RoutineTab;
                    
                    app.RoutineNameLabel.Text = app.ComponentTree.SelectedNodes.Text;
                    app.RoutineDuration.Text = [num2str(app.ComponentTree.SelectedNodes.NodeData.Dur), 'ms'];
                    for el = app.ComponentTree.SelectedNodes.NodeData.Elements
                        try
                            delete(app.RoutineElements.Children)
                        end
                        if el.el.Dur == inf
                            durtag = [num2str(round(el.Start)), 'ms -> End'];
                        else
                            durtag = [num2str(round(el.Start)), 'ms -> ' num2str(el.Start + el.el.Dur), 'ms'];
                        end
                        uitreenode(app.RoutineElements, ...
                            'Text', el.el.ElementName, ...
                            'Tag', 'E', ...
                            'NodeData',  durtag ...
                            );
                    end
                    app.RoutineFactors.Data = app.ComponentTree.SelectedNodes.NodeData.Factors;
                    app.ElDurLabel.Text = '';
                    
                case app.ComponentTree
                    app.ComponentViewer.SelectedTab = app.NoneTab;
            end
        end

        % Value changed function: NewComponentButton
        function NewComponentButtonValueChanged(app, event)
            app.AddComponentPanel.Visible = event.Value;
            
        end

        % Button pushed function: StimulusButton
        function StimulusButtonPushed(app, event)
            app.AddComponentPanel.Visible = false;
            [name, path] = uigetfile([app.rootdir, '\*.*']);
            try
                movefile([path, name], [app.rootdir, 'Stimuli\', name]);
            catch
                error('Could not move file');
            end
            
            loadcomp(app)
            app.NewComponentButton.Value = false;
        end

        % Selection changed function: RoutineElements
        function RoutineElementsSelectionChanged(app, event)
            app.ElDurLabel.Text = app.RoutineElements.SelectedNodes.NodeData;
        end

        % Button pushed function: EditButton, EditButton_2
        function EditComponent(app, event)
            switch app.ComponentTree.SelectedNodes.Parent
                case app.ElementsNode
                    load([app.rootdir, 'Elements\', app.ComponentTree.SelectedNodes.Text])
                    e = Element;
                    e.Parent = app;
                    e.rootdir = app.rootdir;
                    e.loadobj(el)
                    
                case app.RoutinesNode
                    load([app.rootdir, 'Routines\', app.ComponentTree.SelectedNodes.Text])
                    r = Routine;
                    r.rootdir = app.rootdir;
                    r.Parent = app;
                    r.loadobj(rt)
            end
            
        end

        % Button pushed function: DeleteButton, DeleteButton_2, 
        % DeleteButton_3
        function DeleteComponent(app, event)
            switch questdlg('Are you sure you wish to delete this component? This cannot be undone.','Warning','Yes','No','No')
                case 'Yes'
                    switch app.ComponentTree.SelectedNodes.Parent
                        case app.ElementsNode
                            delete([app.rootdir, 'Elements\', app.ComponentTree.SelectedNodes.Text]);
                        case app.RoutinesNode
                            delete([app.rootdir, 'Routines\', app.ComponentTree.SelectedNodes.Text]);
                        case app.StimuliNode
                            delete([app.rootdir, 'Stimuli\', app.ComponentTree.SelectedNodes.Text]);
                    end
                    delete(app.ComponentTree.SelectedNodes);
                case 'No'
                    
            end
            
            loadcomp(app)
            
        end

        % Selection change function: ComponentViewer
        function ComponentViewerSelectionChanged(app, event)
            app.ComponentViewer.SelectedTab = event.OldValue; %prevent changing tabs manually
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewExperimentMenu = uimenu(app.FileMenu);
            app.NewExperimentMenu.MenuSelectedFcn = createCallbackFcn(app, @NewExperiment, true);
            app.NewExperimentMenu.Text = 'New Experiment...';
            app.OpenExperimentMenu = uimenu(app.FileMenu);
            app.OpenExperimentMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenExperiment, true);
            app.OpenExperimentMenu.Text = 'Open Experiment...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameExperiment, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAs, true);
            app.SaveAsMenu.Text = 'Save As...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.ComponentsMenu = uimenu(app.UIFigure);
            app.ComponentsMenu.Text = 'Components';
            app.StimuliMenu = uimenu(app.ComponentsMenu);
            app.StimuliMenu.Text = 'Stimuli';
            app.NewStimulusMenu = uimenu(app.StimuliMenu);
            app.NewStimulusMenu.Text = 'New Stimulus';
            app.ElementsMenu = uimenu(app.ComponentsMenu);
            app.ElementsMenu.Text = 'Elements';
            app.NewElementMenu = uimenu(app.ElementsMenu);
            app.NewElementMenu.MenuSelectedFcn = createCallbackFcn(app, @NewElementMenuSelected, true);
            app.NewElementMenu.Text = 'New Element';
            app.ImportElementMenu = uimenu(app.ElementsMenu);
            app.ImportElementMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportElementMenuSelected, true);
            app.ImportElementMenu.Text = 'Import Element';
            app.RoutinesMenu = uimenu(app.ComponentsMenu);
            app.RoutinesMenu.Text = 'Routines';
            app.NewRoutineMenu = uimenu(app.RoutinesMenu);
            app.NewRoutineMenu.MenuSelectedFcn = createCallbackFcn(app, @NewRoutineMenuSelected, true);
            app.NewRoutineMenu.Text = 'New Routine';
            app.ImportRoutineMenu = uimenu(app.RoutinesMenu);
            app.ImportRoutineMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportRoutineMenuSelected, true);
            app.ImportRoutineMenu.Text = 'Import Routine';
            app.EditDesignMenu = uimenu(app.ComponentsMenu);
            app.EditDesignMenu.MenuSelectedFcn = createCallbackFcn(app, @EditDesignMenuSelected, true);
            app.EditDesignMenu.Text = 'Edit Design...';
            app.EditProcedureMenu = uimenu(app.ComponentsMenu);
            app.EditProcedureMenu.MenuSelectedFcn = createCallbackFcn(app, @EditProcedure, true);
            app.EditProcedureMenu.Text = 'Edit Procedure...';
            app.ExperimentMenu = uimenu(app.UIFigure);
            app.ExperimentMenu.Text = 'Experiment';
            app.RunMenu = uimenu(app.ExperimentMenu);
            app.RunMenu.Text = 'Run';
            app.StopMenu = uimenu(app.ExperimentMenu);
            app.StopMenu.Text = 'Stop';
            app.CompileMenu = uimenu(app.ExperimentMenu);
            app.CompileMenu.Text = 'Compile...';
            app.DesignTree = uitree(app.UIFigure);
            app.DesignTree.Enable = 'off';
            app.WithinSubjectsFactorsNode = uitreenode(app.DesignTree);
            app.WithinSubjectsFactorsNode.NodeData = 'W';
            app.WithinSubjectsFactorsNode.Text = 'Within-Subjects Factors';
            app.BetweenSubjectsFactorsNode = uitreenode(app.DesignTree);
            app.BetweenSubjectsFactorsNode.NodeData = 'B';
            app.BetweenSubjectsFactorsNode.Text = 'Between-Subjects Factors';
            app.MeasuresNode = uitreenode(app.DesignTree);
            app.MeasuresNode.NodeData = 'M';
            app.MeasuresNode.Text = 'Measures';
            app.Node = uitreenode(app.DesignTree);
            app.Node.NodeData = 'N';
            app.Node.Text = '';
            app.EditDesignButton = uibutton(app.UIFigure, 'push');
            app.EditDesignButton.ButtonPushedFcn = createCallbackFcn(app, @EditDesignMenuSelected, true);
            app.EditDesignButton.Enable = 'off';
            app.EditDesignButton.Text = 'Edit Design';
            app.ElementName = uieditfield(app.UIFigure, 'text');
            app.ElementName.ValueChangedFcn = createCallbackFcn(app, @RenameExperiment, true);
            app.ElementName.Enable = 'off';
            app.ElementName.Value = 'Experiment Name';
            app.ElementTree_2 = uitree(app.UIFigure);
            app.ManageDataButton = uibutton(app.UIFigure, 'push');
            app.ManageDataButton.Text = 'Manage Data';
            app.DesignLabel = uilabel(app.UIFigure);
            app.DesignLabel.Enable = 'off';
            app.DesignLabel.Text = 'Design';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.ProcedureTree = uitree(app.UIFigure);
            app.ProcedureTree.SelectionChangedFcn = createCallbackFcn(app, @ProcedureTreeSelectionChanged, true);
            app.ProcedureTree.Enable = 'off';
            app.PTBlank = uitreenode(app.ProcedureTree);
            app.PTBlank.NodeData = 'N';
            app.PTBlank.Text = '';
            app.ProcedureLabel = uilabel(app.UIFigure);
            app.ProcedureLabel.Enable = 'off';
            app.ProcedureLabel.Text = 'Procedure';
            app.EditProcedureButton = uibutton(app.UIFigure, 'push');
            app.EditProcedureButton.ButtonPushedFcn = createCallbackFcn(app, @EditProcedure, true);
            app.EditProcedureButton.Enable = 'off';
            app.EditProcedureButton.Text = 'Edit Procedure';
            app.ComponentPanel = uipanel(app.UIFigure);
            app.ComponentManagerLabel = uilabel(app.ComponentPanel);
            app.ComponentManagerLabel.Enable = 'off';
            app.ComponentManagerLabel.Text = 'Component Manager';
            app.ComponentViewer = uitabgroup(app.ComponentPanel);
            app.ComponentViewer.SelectionChangedFcn = createCallbackFcn(app, @ComponentViewerSelectionChanged, true);
            app.StimulusTab = uitab(app.ComponentViewer);
            app.StimulusTab.Title = 'Stimulus';
            app.StimPreview = uiaxes(app.StimulusTab);
            app.StimPreview.XTick = [];
            app.StimPreview.YTick = [];
            app.StimPreview.Visible = 'off';
            app.DeleteButton_2 = uibutton(app.StimulusTab, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteComponent, true);
            app.DeleteButton_2.Enable = 'off';
            app.DeleteButton_2.Text = 'Delete';
            app.ElementTab = uitab(app.ComponentViewer);
            app.ElementTab.Title = 'Element';
            app.DeleteButton = uibutton(app.ElementTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteComponent, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.EditButton = uibutton(app.ElementTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditComponent, true);
            app.EditButton.Text = 'Edit';
            app.UITable = uitable(app.ElementTab);
            app.UITable.ColumnName = {'Stimuli'; 'Responses'; 'Code'};
            app.UITable.RowName = {};
            app.BgPreview = uiaxes(app.ElementTab);
            app.BgPreview.XLim = [1 750];
            app.BgPreview.YLim = [1 500];
            app.BgPreview.XTick = [];
            app.BgPreview.YTick = [];
            app.BgPreview.Visible = 'off';
            app.BackgroundColourLabel = uilabel(app.ElementTab);
            app.BackgroundColourLabel.Text = 'Background Colour';
            app.ContentsLabel = uilabel(app.ElementTab);
            app.ContentsLabel.Text = 'Contents';
            app.ElementNameLabel = uilabel(app.ElementTab);
            app.ElementNameLabel.Text = 'Name';
            app.DurationLabel = uilabel(app.ElementTab);
            app.DurationLabel.Text = 'Duration';
            app.RGBLabel = uilabel(app.ElementTab);
            app.RGBLabel.Text = '[R, G, B]';
            app.RoutineTab = uitab(app.ComponentViewer);
            app.RoutineTab.Title = 'Routine';
            app.RoutineNameLabel = uilabel(app.RoutineTab);
            app.RoutineNameLabel.Text = 'Name';
            app.RoutineDuration = uilabel(app.RoutineTab);
            app.RoutineDuration.Text = 'Duration';
            app.ElementsLabel = uilabel(app.RoutineTab);
            app.ElementsLabel.Text = 'Elements';
            app.RoutineElements = uitree(app.RoutineTab);
            app.RoutineElements.SelectionChangedFcn = createCallbackFcn(app, @RoutineElementsSelectionChanged, true);
            app.ElDurLabel = uilabel(app.RoutineTab);
            app.ElDurLabel.Text = '';
            app.FactorsLabel = uilabel(app.RoutineTab);
            app.FactorsLabel.Text = 'Factors';
            app.RoutineFactors = uitable(app.RoutineTab);
            app.RoutineFactors.ColumnName = {'Factor'; 'Level'};
            app.RoutineFactors.RowName = {};
            app.DeleteButton_3 = uibutton(app.RoutineTab, 'push');
            app.DeleteButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteComponent, true);
            app.DeleteButton_3.Enable = 'off';
            app.DeleteButton_3.Text = 'Delete';
            app.EditButton_2 = uibutton(app.RoutineTab, 'push');
            app.EditButton_2.ButtonPushedFcn = createCallbackFcn(app, @EditComponent, true);
            app.EditButton_2.Text = 'Edit';
            app.NoneTab = uitab(app.ComponentViewer);
            app.NoneTab.Title = ' ';
            app.ComponentTree = uitree(app.ComponentPanel);
            app.ComponentTree.SelectionChangedFcn = createCallbackFcn(app, @ComponentTreeSelectionChanged, true);
            app.ComponentTree.Enable = 'off';
            app.StimuliNode = uitreenode(app.ComponentTree);
            app.StimuliNode.NodeData = 'Stimuli';
            app.StimuliNode.Text = 'Stimuli';
            app.ElementsNode = uitreenode(app.ComponentTree);
            app.ElementsNode.NodeData = 'Elements';
            app.ElementsNode.Text = 'Elements';
            app.RoutinesNode = uitreenode(app.ComponentTree);
            app.RoutinesNode.NodeData = 'Routines';
            app.RoutinesNode.Text = 'Routines';
            app.cmpBlank = uitreenode(app.ComponentTree);
            app.cmpBlank.Text = '';
            app.AddComponentPanel = uipanel(app.ComponentPanel);
            app.AddComponentPanel.Visible = 'off';
            app.StimulusButton = uibutton(app.AddComponentPanel, 'push');
            app.StimulusButton.ButtonPushedFcn = createCallbackFcn(app, @StimulusButtonPushed, true);
            app.StimulusButton.Text = 'Stimulus';
            app.ElementButton = uibutton(app.AddComponentPanel, 'push');
            app.ElementButton.ButtonPushedFcn = createCallbackFcn(app, @NewElementMenuSelected, true);
            app.ElementButton.Text = 'Element';
            app.RoutineButton = uibutton(app.AddComponentPanel, 'push');
            app.RoutineButton.ButtonPushedFcn = createCallbackFcn(app, @NewRoutineMenuSelected, true);
            app.RoutineButton.Text = 'Routine';
            app.NewComponentButton = uibutton(app.ComponentPanel, 'state');
            app.NewComponentButton.ValueChangedFcn = createCallbackFcn(app, @NewComponentButtonValueChanged, true);
            app.NewComponentButton.Enable = 'off';
            app.NewComponentButton.Text = 'New...';
            app.RefreshButton = uibutton(app.ComponentPanel, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.DataLabel = uilabel(app.UIFigure);
            app.DataLabel.Enable = 'off';
            app.DataLabel.Text = 'Data';
            app.ActionDetails = uitextarea(app.UIFigure);
            app.ActionDetails.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Pedestal

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end