classdef Procedure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Flow                            matlab.ui.container.Tree
        FLBlank                         matlab.ui.container.TreeNode
        StudyProcedureLabel             matlab.ui.control.Label
        AddActionPanel                  matlab.ui.container.Panel
        WhileLoopButton                 matlab.ui.control.Button
        ForLoopButton                   matlab.ui.control.Button
        RandomiserButton                matlab.ui.control.Button
        IfStatementButton               matlab.ui.control.Button
        VariableButton                  matlab.ui.control.Button
        CodeButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        RoutineTab                      matlab.ui.container.Tab
        RoutineEdit                     matlab.ui.control.Button
        RoutineName                     matlab.ui.control.Label
        RoutineDuration                 matlab.ui.control.Label
        RoutineElementNo                matlab.ui.control.Label
        MoveButton                      matlab.ui.control.StateButton
        DeleteButton                    matlab.ui.control.Button
        VariableTab                     matlab.ui.container.Tab
        SetthevalueofEditFieldLabel     matlab.ui.control.Label
        VariableField                   matlab.ui.control.EditField
        TobeequaltoEditFieldLabel       matlab.ui.control.Label
        ValueField                      matlab.ui.control.EditField
        RandomField                     matlab.ui.control.CheckBox
        RandPanel                       matlab.ui.container.Panel
        DistSwitch                      matlab.ui.control.Switch
        RandLabel                       matlab.ui.control.Label
        distributionLabel               matlab.ui.control.Label
        RoundedtothenearestSpinnerLabel  matlab.ui.control.Label
        RoundSpinner                    matlab.ui.control.Spinner
        andSpinnerLabel                 matlab.ui.control.Label
        UpperSpinner                    matlab.ui.control.Spinner
        BetweenSpinnerLabel             matlab.ui.control.Label
        LowerSpinner                    matlab.ui.control.Spinner
        decimalplaceLabel               matlab.ui.control.Label
        MoveButton_2                    matlab.ui.control.StateButton
        DeleteButton_2                  matlab.ui.control.Button
        IfTab                           matlab.ui.container.Tab
        IfField                         matlab.ui.control.EditField
        IfDropDownLabel                 matlab.ui.control.Label
        IfDropDown                      matlab.ui.control.DropDown
        IsDropDownLabel                 matlab.ui.control.Label
        IfIsDropDown                    matlab.ui.control.DropDown
        ComparedtoEditFieldLabel        matlab.ui.control.Label
        IfComparedToField               matlab.ui.control.EditField
        MoveButton_3                    matlab.ui.control.StateButton
        DeleteButton_3                  matlab.ui.control.Button
        ForTab                          matlab.ui.container.Tab
        HowmanyrepeatsSpinnerLabel      matlab.ui.control.Label
        ForRepeatsSpinner               matlab.ui.control.Spinner
        tothevariableLabel              matlab.ui.control.Label
        ForIteratorField                matlab.ui.control.EditField
        EachrepeataddSpinnerLabel       matlab.ui.control.Label
        ForIntervalSpinner              matlab.ui.control.Spinner
        startingatSpinnerLabel          matlab.ui.control.Label
        ForStartSpinner                 matlab.ui.control.Spinner
        MoveButton_4                    matlab.ui.control.StateButton
        DeleteButton_4                  matlab.ui.control.Button
        WhileTab                        matlab.ui.container.Tab
        WhileField                      matlab.ui.control.EditField
        RepeatuntilDropDownLabel        matlab.ui.control.Label
        WhileDropDown                   matlab.ui.control.DropDown
        IsDropDown_2Label               matlab.ui.control.Label
        WhileIsDropDown                 matlab.ui.control.DropDown
        ComparedtoEditField_2Label      matlab.ui.control.Label
        WhileComparedToField            matlab.ui.control.EditField
        tothevariableLabel_2            matlab.ui.control.Label
        WhileIteratorField              matlab.ui.control.EditField
        EachrepeataddSpinnerLabel_2     matlab.ui.control.Label
        WhileIntervalSpinner            matlab.ui.control.Spinner
        startingatSpinnerLabel_2        matlab.ui.control.Label
        WhileStartSpinner               matlab.ui.control.Spinner
        MoveButton_5                    matlab.ui.control.StateButton
        DeleteButton_5                  matlab.ui.control.Button
        RandomTab                       matlab.ui.container.Tab
        RandLab1                        matlab.ui.control.Label
        RandLab2                        matlab.ui.control.Label
        RandSpinner                     matlab.ui.control.Spinner
        ButtonGroup                     matlab.ui.container.ButtonGroup
        RandFullButton                  matlab.ui.control.ToggleButton
        RandEvenButton                  matlab.ui.control.ToggleButton
        MoveButton_6                    matlab.ui.control.StateButton
        DeleteButton_6                  matlab.ui.control.Button
        CodeTab                         matlab.ui.container.Tab
        TyperawMATLABcodeTextAreaLabel  matlab.ui.control.Label
        CodeText                        matlab.ui.control.TextArea
        MoveButton_7                    matlab.ui.control.StateButton
        DeleteButton_7                  matlab.ui.control.Button
        NoneTab                         matlab.ui.container.Tab
        AddActionNextButton             matlab.ui.control.StateButton
        AddActionWithinButton           matlab.ui.control.StateButton
        RoutinePanel                    matlab.ui.container.Panel
        RoutineTree                     matlab.ui.container.Tree
        RTBlank                         matlab.ui.container.TreeNode
        AddRoutineWithinButton          matlab.ui.control.Button
        AddRoutineNextButton            matlab.ui.control.Button
        SavedRoutinesLabel              matlab.ui.control.Label
        RoutineDetailsTab               matlab.ui.container.Panel
        RoutineFileEdit                 matlab.ui.control.Button
        RoutineFileName                 matlab.ui.control.Label
        RoutineFileDur                  matlab.ui.control.Label
        RoutineFileElements             matlab.ui.control.Label
        RoutineFileDesign               matlab.ui.control.Table
        RoutineFileDesignLabel          matlab.ui.control.Label
        xButton                         matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        MoveMode % Toggle between move mode and regular selection
        rootdir
    end
    
    methods (Access = public)
        
        function loadSpec(app, node)
            switch node.Tag
                case 'If'
                    app.IfDropDown.Value = node.NodeData.IfDropDown;
                    app.IfField.Value = node.NodeData.IfField;
                    app.IfIsDropDown.Value = node.NodeData.IfIsDropDown;
                    app.IfComparedToField.Value = node.NodeData.IfComparedToField;
                    
                case 'Else'
                    app.IfDropDown.Value = node.Parent.NodeData.IfDropDown;
                    app.IfField.Value = node.Parent.NodeData.IfField;
                    app.IfIsDropDown.Value = node.Parent.NodeData.IfIsDropDown;
                    app.IfComparedToField.Value = node.Parent.NodeData.IfComparedToField;
                    
                case 'While'
                    if any(contains(app.WhileDropDown.Items, node.NodeData.WhileSubject)) & ~isequal(node.NodeData.WhileSubject, 'Custom')
                        app.WhileDropDown.Value = node.NodeData.WhileSubject;
                        app.WhileField.Value = '';
                        app.WhileField.Enable = 'off';
                    else
                        app.WhileDropDown.Value = 'Custom';
                        app.WhileField.Value = node.NodeData.WhileSubject;
                        app.WhileField.Enable = 'on';
                    end
                    app.WhileIsDropDown.Value = node.NodeData.WhileIsDropDown;
                    app.WhileComparedToField.Value = node.NodeData.WhileComparedToField;
                    app.WhileIntervalSpinner.Value = node.NodeData.WhileIntervalSpinner;
                    app.WhileIteratorField.Value = node.NodeData.WhileIteratorField;
                    app.WhileStartSpinner.Value = node.NodeData.WhileStartSpinner;
                    
                case 'For'
                    app.ForRepeatsSpinner.Value = node.NodeData.ForRepeatsSpinner;
                    app.ForIntervalSpinner.Value = node.NodeData.ForIntervalSpinner;
                    app.ForIteratorField.Value = node.NodeData.ForIteratorField;
                    app.ForStartSpinner.Value = node.NodeData.ForStartSpinner;
                    
                case 'Random'
                    app.RandSpinner.Value = node.NodeData.RandSpinner;
                    app.RandSpinner.Limits(2) = length({node.Children});
                    app.RandFullButton.Value = node.NodeData.RandFullButton;
                    app.RandEvenButton.Value = node.NodeData.RandEvenButton;
                    
                case 'R'
                    app.RoutineName.Text = node.NodeData.Name;
                    app.RoutineDuration.Text = [num2str(node.NodeData.Dur), ' ms'];
                    app.RoutineElementNo.Text = [num2str(length(node.NodeData.Elements)), ' Elements'];
                    
                case 'Code'
                    app.CodeText.Value = node.NodeData.Code;
                    
                case 'Variable'
                    app.VariableField.Value = node.NodeData.Variable;
                    app.ValueField.Value = node.NodeData.Value;
                    app.RandomField.Value = contains(node.NodeData.Value, 'rand');
                    app.DistSwitch.Value = node.NodeData.Random.Distribution;
                    app.LowerSpinner.Value = node.NodeData.Random.Lower;
                    app.UpperSpinner.Value = node.NodeData.Random.Upper;
                    app.RoundSpinner.Value = node.NodeData.Random.Round;
            end
        end
        
        function saveobj(app)
            delete(app.FLBlank);
            pr = app.Flow.Children;
            save(app.rootdir, '\Design\Procedure.mat', 'pr')
        end
        
        function cycle(app, children, parent)
            for n = 1:length(children)
                child = uitreenode(parent, ...
                    'Text', children(n).Text, ...
                    'Tag', children(n).Tag, ...
                    'NodeData', children(n).NodeData ...
                    );
                if any(strcmp(child.Tag, {'For', 'If', 'Else', 'While', 'Random'}))
                    cycle(app, children(n).Children, child);
                end
            end
        end
        
        function loadobj(app, pr)
            for n = 1:length(pr)
                parent = uitreenode(app.Flow, app.FLBlank, 'before', ...
                    'Text', pr(n).Text, ...
                    'Tag', pr(n).Tag, ...
                    'NodeData', pr(n).NodeData ...
                    );
                if any(strcmp(parent.Tag, {'For', 'If', 'Else', 'While', 'Random'}))
                    cycle(app, pr(n).Children, parent);
                end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center');
            app.TabGroup.SelectedTab = app.NoneTab;
            
            routineList = dir([app.rootdir, '\Routines\*.mat']);
            for r = 1:length(routineList)
                load([app.rootdir, '\Routines\' routineList(r).name]);
                uitreenode(app.RoutineTree,  app.RTBlank, 'before', ...
                    'Text', rt.Name, ...
                    'Tag', 'R', ...
                    'NodeData', rt ...
                    );
            end
            app.MoveMode = false;
            
            app.UIFigure.Visible = 'on';

        end

        % Button pushed function: CodeButton, ForLoopButton, 
        % IfStatementButton, RandomiserButton, VariableButton, 
        % WhileLoopButton
        function AddNode(app, event)
            switch app.AddActionPanel.Tag
                case 'Next'
                    par = app.Flow;
                    sib = app.FLBlank;
                    
                case 'Within'
                    par = app.Flow.SelectedNodes;
            end
            
            switch event.Source
                case app.VariableButton
                    tag = 'Variable';
                    spec = struct('Variable', 'trial', ...
                        'Value', '', ...
                        'Random', struct(...
                        'Distribution', 'rand', ...
                        'Lower', 0, ...
                        'Upper', 1, ...
                        'Round', 1 ...
                        ));
                    name = [tag, '...'];
                case app.WhileLoopButton
                    tag = 'While';
                    spec = struct(...
                        'WhileSubject', 'Trial', ...
                        'WhileIsDropDown', 'Less Than', ...
                        'WhileComparedToField', '', ...
                        'WhileIntervalSpinner', 1, ...
                        'WhileIteratorField', 'trial', ...
                        'WhileStartSpinner', 1 ...
                        );
                    name = [tag, '...'];
                case app.ForLoopButton
                    tag = 'For';
                    spec = struct('ForRepeatsSpinner', 1, ...
                        'ForIntervalSpinner', 1, ...
                        'ForIteratorField', 'trial', ...
                        'ForStartSpinner', 1 ...
                        );
                    name = [tag, '...'];
                case app.RandomiserButton
                    tag = 'Random';
                    spec = struct('RandSpinner', 0, ...
                        'RandFullButton', true, ...
                        'RandEvenButton', false ...
                        );
                    name = 'Randomise...';
                case app.IfStatementButton
                    tag = 'If';
                    spec = struct('IfDropDown', 'Trial', ...
                        'IfField', '', ...
                        'IfIsDropDown', 'Less Than', ...
                        'IfComparedToField', '' ...
                        );
                    name = [tag, '...'];
                case app.CodeButton
                    tag = 'Code';
                    spec = struct('Code', '');
                    name = [tag, '...'];
            end
            
            try
                child = uitreenode(par, sib, 'after', ...
                    'Text', name, ...
                    'NodeData', spec, ...
                    'Tag', tag ...
                    );
            catch
                child = uitreenode(par, ...
                    'Text', name, ...
                    'NodeData', spec, ...
                    'Tag', tag ...
                    );
            end
            
            if event.Source == app.IfStatementButton
                uitreenode(child, ...
                    'Text', 'Else...', ...
                    'Tag', 'Else' ...
                    );
            end
            
            switch par.Tag
                case 'If'
                    i = strcmp({par.Children.Tag},'Else');
                    move(par.Children(i), par.Children(end), 'after');
            end
            
            move(app.FLBlank, app.Flow.Children(end), 'after');
            app.AddActionPanel.Visible = false;
            app.AddActionNextButton.Value = false;
            app.AddActionWithinButton.Value = false;
            
        end

        % Selection changed function: Flow
        function FlowSelectionChanged(app, event)
            if ~app.MoveMode
                    app.AddActionWithinButton.Enable = any(...
                        strcmp(app.Flow.SelectedNodes.Tag, {'If', 'Else', 'While', 'For', 'Random'}) ...
                        );
                    try
                        app.AddRoutineWithinButton.Enable = any([...
                            strcmp(app.Flow.SelectedNodes.Tag, {'If', 'Else', 'While', 'For', 'Random'}), ...
                            isequal(app.RoutineTree.SelectedNodes.Tag, 'R')]);
                    end
                    
                    switch event.SelectedNodes.Tag
                        case 'If'
                            app.TabGroup.SelectedTab = app.IfTab;
                        case 'Else'
                            app.TabGroup.SelectedTab = app.IfTab;
                        case 'While'
                            app.TabGroup.SelectedTab = app.WhileTab;
                        case 'For'
                            app.TabGroup.SelectedTab = app.ForTab;
                        case 'Random'
                            app.TabGroup.SelectedTab = app.RandomTab;
                        case 'R'
                            app.TabGroup.SelectedTab = app.RoutineTab;
                        case 'Code'
                            app.TabGroup.SelectedTab = app.CodeTab;
                        case 'Variable'
                            app.TabGroup.SelectedTab = app.VariableTab;
                        otherwise
                            app.TabGroup.SelectedTab = app.NoneTab;
                    end
                    
                    loadSpec(app, app.Flow.SelectedNodes)
            else
                move(event.PreviousSelectedNodes,event.SelectedNodes,'before')
                app.MoveMode = false;
                buttons = [app.MoveButton, app.MoveButton_2, app.MoveButton_3, app.MoveButton_4, app.MoveButton_5, app.MoveButton_6, app.MoveButton_7];
                for b = 1:length(buttons)
                    buttons(1).Value = false;
                end
                app.Flow.SelectedNodes = event.PreviousSelectedNodes;
            end
        end

        % Value changed function: AddActionNextButton
        function AddActionNext(app, event)
            if event.Value
                app.AddActionPanel.Visible = true;
                app.AddActionPanel.Tag = 'Next';
                app.AddActionWithinButton.Value = false;
                app.AddActionPanel.Position(1) = event.Source.Position(1);
            else
                app.AddActionPanel.Visible = false;
                app.AddActionPanel.Tag = '';
            end
        end

        % Value changed function: AddActionWithinButton
        function AddActionWithin(app, event)
            if event.Value
                app.AddActionPanel.Visible = true;
                app.AddActionPanel.Tag = 'Within';
                app.AddActionNextButton.Value = false;
                app.AddActionPanel.Position(1) = event.Source.Position(1);
            else
                app.AddActionPanel.Visible = false;
                app.AddActionPanel.Tag = '';
            end
        end

        % Button pushed function: DeleteButton, DeleteButton_2, 
        % DeleteButton_3, DeleteButton_4, DeleteButton_5, 
        % DeleteButton_6, DeleteButton_7
        function DeleteButtonPushed(app, event)
            delete(app.Flow.SelectedNodes)
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.TabGroup.SelectedTab = event.OldValue; %prevent changing tabs manually
            
        end

        % Callback function: IfComparedToField, IfComparedToField, 
        % IfDropDown, IfField, IfField, IfIsDropDown
        function UpdateIfTab(app, event)
            app.IfField.Enable = isequal(app.IfDropDown.Value, 'Custom');
            
            node = app.Flow.SelectedNodes;
            node.NodeData.IfDropDown = app.IfDropDown.Value;
            node.NodeData.IfField = app.IfField.Value;
            node.NodeData.IfIsDropDown = app.IfIsDropDown.Value;
            node.NodeData.IfComparedToField = app.IfComparedToField.Value;
            
        end

        % Callback function: ButtonGroup, RandSpinner, RandSpinner
        function UpdateRandomTab(app, event)
            node = app.Flow.SelectedNodes;
            node.NodeData.RandSpinner = app.RandSpinner.Value;
            node.NodeData.RandFullButton = app.RandFullButton.Value;
            node.NodeData.RandEvenButton = app.RandEvenButton.Value;
            
        end

        % Callback function: ForIntervalSpinner, ForIntervalSpinner, 
        % ForIteratorField, ForIteratorField, ForRepeatsSpinner, 
        % ForRepeatsSpinner
        function UpdateForTab(app, event)
            node = app.Flow.SelectedNodes;
            node.NodeData.ForRepeatsSpinner = app.ForRepeatsSpinner.Value;
            node.NodeData.ForIntervalSpinner = app.ForIntervalSpinner.Value;
            node.NodeData.ForIteratorField = app.ForIteratorField.Value;
            node.NodeData.ForStartSpinner = app.ForStartspinner.Value;
            
        end

        % Callback function: WhileComparedToField, 
        % WhileComparedToField, WhileDropDown, WhileField, 
        % WhileField, WhileIntervalSpinner, WhileIntervalSpinner, 
        % WhileIsDropDown, WhileIteratorField, WhileIteratorField, 
        % WhileStartSpinner, WhileStartSpinner
        function UpdateWhileTab(app, event)
            app.WhileField.Enable = isequal(app.WhileDropDown.Value, 'Custom');
            
            node = app.Flow.SelectedNodes;
            if isequal(app.WhileDropDown.Value, 'Custom')
               node.NodeData.WhileSubject = app.WhileField.Value;
            else
                node.NodeData.WhileSubject = app.WhileDropDown.Value;
            end
            node.NodeData.WhileComparedToField = app.WhileComparedToField.Value;
            node.NodeData.WhileIntervalSpinner = app.WhileIntervalSpinner.Value;
            node.NodeData.WhileIteratorField = app.WhileIteratorField.Value;
            node.NodeData.WhileStartSpinner = app.WhileStartSpinner.Value;

        end

        % Button pushed function: RoutineEdit
        function EditRoutine(app, event)
            r = Routine;
            r.loadobj(app.Flow.SelectedNode.NodeData);
            
        end

        % Selection changed function: RoutineTree
        function RoutineTreeSelectionChanged(app, event)
            app.AddRoutineNextButton.Enable = isequal(event.SelectedNodes.Tag, 'R');
            app.RoutineDetailsTab.Visible = isequal(event.SelectedNodes.Tag, 'R');
            try
            app.AddRoutineWithinButton.Enable = any([...
                strcmp(app.Flow.SelectedNodes.Tag, {'If', 'Else', 'While', 'For', 'Random'}), ...
                isequal(app.RoutineTree.SelectedNodes.Tag, 'R')]);
            end
            
            if isequal(event.SelectedNodes.Tag, 'R')
                app.RoutineFileName.Text = event.SelectedNodes.NodeData.Name;
                app.RoutineFileDur.Text = [num2str(event.SelectedNodes.NodeData.Dur) 'ms'];
                app.RoutineFileElements.Text = [num2str(length(event.SelectedNodes.NodeData.Elements)) ' Elements'];
                app.RoutineFileDesign.Data = event.SelectedNodes.NodeData.Factors;
            else
                app.RoutineFileName.Text = '';
                app.RoutineFileDur.Text = '';
                app.RoutineFileElements.Text = '';
                app.RoutineFileDesign.Data = [];
            end
            
        end

        % Callback function: DistSwitch, LowerSpinner, LowerSpinner, 
        % RandomField, RoundSpinner, RoundSpinner, UpperSpinner, 
        % UpperSpinner, ValueField, ValueField, VariableField, 
        % VariableField
        function UpdateVarTab(app, event)
            app.RoundSpinner.Value = round(app.RoundSpinner.Value);
            
            app.RandPanel.Visible = app.RandomField.Value;
            app.ValueField.Enable = ~app.RandomField.Value;
            
            if app.RandomField.Value
                app.ValueField.Value = ['round(' ...
                    num2str(app.LowerSpinner.Value) ...
                    ' + ' app.DistSwitch.Value '()*' ...
                    num2str(app.UpperSpinner.Value) ', ' ...
                    num2str(app.RoundSpinner.Value) ')'];
            end
            
            node = app.Flow.SelectedNodes;
            node.NodeData.Variable = app.VariableField.Value;
            node.NodeData.Value = app.ValueField.Value;
            node.NodeData.Random.Distribution = app.DistSwitch.Value;
            node.NodeData.Random.Lower = app.LowerSpinner.Value;
            node.NodeData.Random.Upper = app.UpperSpinner.Value;
            node.NodeData.Random.Round = app.RoundSpinner.Value;
            
        end

        % Button pushed function: AddRoutineNextButton
        function AddRoutineNext(app, event)
            uitreenode(app.Flow, app.FLBlank, 'before', ...
                'Text', app.RoutineTree.SelectedNodes.Text, ...
                'NodeData', app.RoutineTree.SelectedNodes.NodeData, ...
                'Tag', app.RoutineTree.SelectedNodes.Tag ...
                );
            app.RoutineDetailsTab.Visible = 'off';
            
        end

        % Button pushed function: AddRoutineWithinButton
        function AddRoutineWithinButtonPushed(app, event)
            if ~isempty(app.Flow.SelectedNodes.Children)
                uitreenode(app.Flow.SelectedNodes, app.Flow.SelectedNodes.Children(1), 'before', ...
                    'Text', app.RoutineTree.SelectedNodes.Text, ...
                    'NodeData', app.RoutineTree.SelectedNodes.NodeData, ...
                    'Tag', app.RoutineTree.SelectedNodes.Tag ...
                    );
            else
                uitreenode(app.Flow.SelectedNodes, ...
                    'Text', app.RoutineTree.SelectedNodes.Text, ...
                    'NodeData', app.RoutineTree.SelectedNodes.NodeData, ...
                    'Tag', app.RoutineTree.SelectedNodes.Tag ...
                    );
            end
            
            expand(app.Flow.SelectedNodes)
            app.RoutineDetailsTab.Visible = 'off';
            
        end

        % Value changed function: CodeText
        function UpdateCodeTab(app, event)
            node = app.Flow.SelectedNodes;
            node.NodeData.Code = app.Codetext.Value;
            
        end

        % Button pushed function: xButton
        function HideRoutineDetailsTab(app, event)
            app.RoutineDetailsTab.Visible = false;
            
        end

        % Button pushed function: RoutineFileEdit
        function RoutineFileEditButtonPushed(app, event)
            r = Routine;
            r.loadobj(app.RoutineTree.SelectedNodes.NodeData);
            waitfor(r)
            
            i = app.RoutineTree.Children ~= app.RTBlank;
            delete(app.RoutineTree.Children(i))
            routineList = dir(app.rootdir, '\Routines\*.mat');
            for r = 1:length(routineList)
                load([app.rootdir, '\Routines\' routineList(r).name]);
                uitreenode(app.RoutineTree,  app.RTBlank, 'before', ...
                    'Text', rt.Name, ...
                    'Tag', 'R', ...
                    'NodeData', rt ...
                    );
            end
            app.RoutineDetailsTab.Visible = 'off';
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveobj(app)
            delete(app)
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
            
        end

        % Value changed function: MoveButton, MoveButton_2, 
        % MoveButton_3, MoveButton_4, MoveButton_5, MoveButton_6, 
        % MoveButton_7
        function ToggleMoveMode(app, event)
            app.MoveMode = event.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Flow = uitree(app.UIFigure);
            app.Flow.SelectionChangedFcn = createCallbackFcn(app, @FlowSelectionChanged, true);
            app.FLBlank = uitreenode(app.Flow);
            app.FLBlank.Text = '';
            app.StudyProcedureLabel = uilabel(app.UIFigure);
            app.StudyProcedureLabel.Text = 'Study Procedure';
            app.AddActionPanel = uipanel(app.UIFigure);
            app.AddActionPanel.Visible = 'off';
            app.WhileLoopButton = uibutton(app.AddActionPanel, 'push');
            app.WhileLoopButton.ButtonPushedFcn = createCallbackFcn(app, @AddNode, true);
            app.WhileLoopButton.Text = 'While Loop';
            app.ForLoopButton = uibutton(app.AddActionPanel, 'push');
            app.ForLoopButton.ButtonPushedFcn = createCallbackFcn(app, @AddNode, true);
            app.ForLoopButton.Text = 'For Loop';
            app.RandomiserButton = uibutton(app.AddActionPanel, 'push');
            app.RandomiserButton.ButtonPushedFcn = createCallbackFcn(app, @AddNode, true);
            app.RandomiserButton.Text = 'Randomiser';
            app.IfStatementButton = uibutton(app.AddActionPanel, 'push');
            app.IfStatementButton.ButtonPushedFcn = createCallbackFcn(app, @AddNode, true);
            app.IfStatementButton.Text = 'If Statement';
            app.VariableButton = uibutton(app.AddActionPanel, 'push');
            app.VariableButton.ButtonPushedFcn = createCallbackFcn(app, @AddNode, true);
            app.VariableButton.Text = 'Variable';
            app.CodeButton = uibutton(app.AddActionPanel, 'push');
            app.CodeButton.ButtonPushedFcn = createCallbackFcn(app, @AddNode, true);
            app.CodeButton.Text = 'Code';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RoutineTab = uitab(app.TabGroup);
            app.RoutineTab.Title = 'Routine';
            app.RoutineEdit = uibutton(app.RoutineTab, 'push');
            app.RoutineEdit.ButtonPushedFcn = createCallbackFcn(app, @EditRoutine, true);
            app.RoutineEdit.Text = 'Edit';
            app.RoutineName = uilabel(app.RoutineTab);
            app.RoutineName.Text = 'Routine Name';
            app.RoutineDuration = uilabel(app.RoutineTab);
            app.RoutineDuration.Text = 'Duration';
            app.RoutineElementNo = uilabel(app.RoutineTab);
            app.RoutineElementNo.Text = '# Elements';
            app.MoveButton = uibutton(app.RoutineTab, 'state');
            app.MoveButton.ValueChangedFcn = createCallbackFcn(app, @ToggleMoveMode, true);
            app.MoveButton.Text = 'Move';
            app.DeleteButton = uibutton(app.RoutineTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.VariableTab = uitab(app.TabGroup);
            app.VariableTab.Title = 'Variable';
            app.SetthevalueofEditFieldLabel = uilabel(app.VariableTab);
            app.SetthevalueofEditFieldLabel.Text = 'Set the value of...';
            app.VariableField = uieditfield(app.VariableTab, 'text');
            app.VariableField.ValueChangedFcn = createCallbackFcn(app, @UpdateVarTab, true);
            app.VariableField.ValueChangingFcn = createCallbackFcn(app, @UpdateVarTab, true);
            app.TobeequaltoEditFieldLabel = uilabel(app.VariableTab);
            app.TobeequaltoEditFieldLabel.Text = 'To be equal to...';
            app.ValueField = uieditfield(app.VariableTab, 'text');
            app.ValueField.ValueChangedFcn = createCallbackFcn(app, @UpdateVarTab, true);
            app.ValueField.ValueChangingFcn = createCallbackFcn(app, @UpdateVarTab, true);
            app.RandomField = uicheckbox(app.VariableTab);
            app.RandomField.ValueChangedFcn = createCallbackFcn(app, @UpdateVarTab, true);
            app.RandomField.Text = 'Random Value';
            app.RandPanel = uipanel(app.VariableTab);
            app.RandPanel.Visible = 'off';
            app.DistSwitch = uiswitch(app.RandPanel, 'slider');
            app.DistSwitch.Items = {'Uniform', 'Normal'};
            app.DistSwitch.ItemsData = {'rand', 'randn'};
            app.DistSwitch.ValueChangedFcn = createCallbackFcn(app, @UpdateVarTab, true);
            app.DistSwitch.Value = 'rand';
            app.RandLabel = uilabel(app.RandPanel);
            app.RandLabel.Text = {'Generate a random number '; 'from a...'};
            app.distributionLabel = uilabel(app.RandPanel);
            app.distributionLabel.Text = '...distribution.';
            app.RoundedtothenearestSpinnerLabel = uilabel(app.RandPanel);
            app.RoundedtothenearestSpinnerLabel.Text = 'Rounded to the nearest...';
            app.RoundSpinner = uispinner(app.RandPanel);
            app.RoundSpinner.ValueChangingFcn = createCallbackFcn(app, @UpdateVarTab, true);
            app.RoundSpinner.Limits = [0 Inf];
            app.RoundSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateVarTab, true);
            app.RoundSpinner.Value = 1;
            app.andSpinnerLabel = uilabel(app.RandPanel);
            app.andSpinnerLabel.Text = '...and...';
            app.UpperSpinner = uispinner(app.RandPanel);
            app.UpperSpinner.ValueChangingFcn = createCallbackFcn(app, @UpdateVarTab, true);
            app.UpperSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateVarTab, true);
            app.UpperSpinner.Value = 1;
            app.BetweenSpinnerLabel = uilabel(app.RandPanel);
            app.BetweenSpinnerLabel.Text = 'Between...';
            app.LowerSpinner = uispinner(app.RandPanel);
            app.LowerSpinner.ValueChangingFcn = createCallbackFcn(app, @UpdateVarTab, true);
            app.LowerSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateVarTab, true);
            app.decimalplaceLabel = uilabel(app.RandPanel);
            app.decimalplaceLabel.Text = '...decimal place';
            app.MoveButton_2 = uibutton(app.VariableTab, 'state');
            app.MoveButton_2.ValueChangedFcn = createCallbackFcn(app, @ToggleMoveMode, true);
            app.MoveButton_2.Text = 'Move';
            app.DeleteButton_2 = uibutton(app.VariableTab, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton_2.Text = 'Delete';
            app.IfTab = uitab(app.TabGroup);
            app.IfTab.Title = 'If';
            app.IfField = uieditfield(app.IfTab, 'text');
            app.IfField.ValueChangedFcn = createCallbackFcn(app, @UpdateIfTab, true);
            app.IfField.ValueChangingFcn = createCallbackFcn(app, @UpdateIfTab, true);
            app.IfField.Enable = 'off';
            app.IfDropDownLabel = uilabel(app.IfTab);
            app.IfDropDownLabel.Text = 'If...';
            app.IfDropDown = uidropdown(app.IfTab);
            app.IfDropDown.Items = {'Trial', 'Time', 'Time Since...', 'Response to...', 'Custom'};
            app.IfDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateIfTab, true);
            app.IfDropDown.Value = 'Trial';
            app.IsDropDownLabel = uilabel(app.IfTab);
            app.IsDropDownLabel.Text = 'Is...';
            app.IfIsDropDown = uidropdown(app.IfTab);
            app.IfIsDropDown.Items = {'Less Than', 'Less Than or Equal To', 'Equal To', 'Greater Than or Equal To', 'Greater Than', 'Not Equal To'};
            app.IfIsDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateIfTab, true);
            app.IfIsDropDown.Value = 'Less Than';
            app.ComparedtoEditFieldLabel = uilabel(app.IfTab);
            app.ComparedtoEditFieldLabel.Text = 'Compared to...';
            app.IfComparedToField = uieditfield(app.IfTab, 'text');
            app.IfComparedToField.ValueChangedFcn = createCallbackFcn(app, @UpdateIfTab, true);
            app.IfComparedToField.ValueChangingFcn = createCallbackFcn(app, @UpdateIfTab, true);
            app.MoveButton_3 = uibutton(app.IfTab, 'state');
            app.MoveButton_3.ValueChangedFcn = createCallbackFcn(app, @ToggleMoveMode, true);
            app.MoveButton_3.Text = 'Move';
            app.DeleteButton_3 = uibutton(app.IfTab, 'push');
            app.DeleteButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton_3.Text = 'Delete';
            app.ForTab = uitab(app.TabGroup);
            app.ForTab.Title = 'For';
            app.HowmanyrepeatsSpinnerLabel = uilabel(app.ForTab);
            app.HowmanyrepeatsSpinnerLabel.Text = 'How many repeats?';
            app.ForRepeatsSpinner = uispinner(app.ForTab);
            app.ForRepeatsSpinner.ValueChangingFcn = createCallbackFcn(app, @UpdateForTab, true);
            app.ForRepeatsSpinner.Limits = [1 Inf];
            app.ForRepeatsSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateForTab, true);
            app.ForRepeatsSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.ForRepeatsSpinner.Value = 1;
            app.tothevariableLabel = uilabel(app.ForTab);
            app.tothevariableLabel.Text = '...to the variable...';
            app.ForIteratorField = uieditfield(app.ForTab, 'text');
            app.ForIteratorField.ValueChangedFcn = createCallbackFcn(app, @UpdateForTab, true);
            app.ForIteratorField.ValueChangingFcn = createCallbackFcn(app, @UpdateForTab, true);
            app.ForIteratorField.Tooltip = {'Name a variable or variables (seperated by commas) to iterate each time this loop loops.'; 'You can refer back to this variable later if you want anything to change on each loop. "trial" will automatically change certain elements each time.'};
            app.ForIteratorField.Value = 'trial';
            app.EachrepeataddSpinnerLabel = uilabel(app.ForTab);
            app.EachrepeataddSpinnerLabel.Text = 'Each repeat, add...';
            app.ForIntervalSpinner = uispinner(app.ForTab);
            app.ForIntervalSpinner.ValueChangingFcn = createCallbackFcn(app, @UpdateForTab, true);
            app.ForIntervalSpinner.Limits = [1 Inf];
            app.ForIntervalSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateForTab, true);
            app.ForIntervalSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.ForIntervalSpinner.Value = 1;
            app.startingatSpinnerLabel = uilabel(app.ForTab);
            app.startingatSpinnerLabel.Text = '...starting at...';
            app.ForStartSpinner = uispinner(app.ForTab);
            app.ForStartSpinner.Limits = [1 Inf];
            app.ForStartSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.ForStartSpinner.Value = 1;
            app.MoveButton_4 = uibutton(app.ForTab, 'state');
            app.MoveButton_4.ValueChangedFcn = createCallbackFcn(app, @ToggleMoveMode, true);
            app.MoveButton_4.Text = 'Move';
            app.DeleteButton_4 = uibutton(app.ForTab, 'push');
            app.DeleteButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton_4.Text = 'Delete';
            app.WhileTab = uitab(app.TabGroup);
            app.WhileTab.Title = 'While';
            app.WhileField = uieditfield(app.WhileTab, 'text');
            app.WhileField.ValueChangedFcn = createCallbackFcn(app, @UpdateWhileTab, true);
            app.WhileField.ValueChangingFcn = createCallbackFcn(app, @UpdateWhileTab, true);
            app.WhileField.Enable = 'off';
            app.RepeatuntilDropDownLabel = uilabel(app.WhileTab);
            app.RepeatuntilDropDownLabel.Text = 'Repeat until...';
            app.WhileDropDown = uidropdown(app.WhileTab);
            app.WhileDropDown.Items = {'Trial', 'Time', 'Time Since...', 'Custom'};
            app.WhileDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateWhileTab, true);
            app.WhileDropDown.Value = 'Trial';
            app.IsDropDown_2Label = uilabel(app.WhileTab);
            app.IsDropDown_2Label.Text = 'Is...';
            app.WhileIsDropDown = uidropdown(app.WhileTab);
            app.WhileIsDropDown.Items = {'Less Than', 'Less Than or Equal To', 'Equal To', 'Greater Than or Equal To', 'Greater Than', 'Not Equal To'};
            app.WhileIsDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateWhileTab, true);
            app.WhileIsDropDown.Value = 'Less Than';
            app.ComparedtoEditField_2Label = uilabel(app.WhileTab);
            app.ComparedtoEditField_2Label.Text = 'Compared to...';
            app.WhileComparedToField = uieditfield(app.WhileTab, 'text');
            app.WhileComparedToField.ValueChangedFcn = createCallbackFcn(app, @UpdateWhileTab, true);
            app.WhileComparedToField.ValueChangingFcn = createCallbackFcn(app, @UpdateWhileTab, true);
            app.tothevariableLabel_2 = uilabel(app.WhileTab);
            app.tothevariableLabel_2.Text = '...to the variable...';
            app.WhileIteratorField = uieditfield(app.WhileTab, 'text');
            app.WhileIteratorField.ValueChangedFcn = createCallbackFcn(app, @UpdateWhileTab, true);
            app.WhileIteratorField.ValueChangingFcn = createCallbackFcn(app, @UpdateWhileTab, true);
            app.WhileIteratorField.Tooltip = {'Name a variable or variables (seperated by commas) to iterate each time this loop loops.'; 'You can refer back to this variable later if you want anything to change on each loop. "trial" will automatically change certain elements each time.'};
            app.WhileIteratorField.Value = 'trial';
            app.EachrepeataddSpinnerLabel_2 = uilabel(app.WhileTab);
            app.EachrepeataddSpinnerLabel_2.Text = 'Each repeat, add...';
            app.WhileIntervalSpinner = uispinner(app.WhileTab);
            app.WhileIntervalSpinner.ValueChangingFcn = createCallbackFcn(app, @UpdateWhileTab, true);
            app.WhileIntervalSpinner.Limits = [1 Inf];
            app.WhileIntervalSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateWhileTab, true);
            app.WhileIntervalSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.WhileIntervalSpinner.Value = 1;
            app.startingatSpinnerLabel_2 = uilabel(app.WhileTab);
            app.startingatSpinnerLabel_2.Text = '...starting at...';
            app.WhileStartSpinner = uispinner(app.WhileTab);
            app.WhileStartSpinner.ValueChangingFcn = createCallbackFcn(app, @UpdateWhileTab, true);
            app.WhileStartSpinner.Limits = [1 Inf];
            app.WhileStartSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateWhileTab, true);
            app.WhileStartSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.WhileStartSpinner.Value = 1;
            app.MoveButton_5 = uibutton(app.WhileTab, 'state');
            app.MoveButton_5.ValueChangedFcn = createCallbackFcn(app, @ToggleMoveMode, true);
            app.MoveButton_5.Text = 'Move';
            app.DeleteButton_5 = uibutton(app.WhileTab, 'push');
            app.DeleteButton_5.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton_5.Text = 'Delete';
            app.RandomTab = uitab(app.TabGroup);
            app.RandomTab.Title = 'Random';
            app.RandLab1 = uilabel(app.RandomTab);
            app.RandLab1.Text = 'In random order run...';
            app.RandLab2 = uilabel(app.RandomTab);
            app.RandLab2.Text = '...of the nodes in this loop';
            app.RandSpinner = uispinner(app.RandomTab);
            app.RandSpinner.ValueChangingFcn = createCallbackFcn(app, @UpdateRandomTab, true);
            app.RandSpinner.Limits = [0 Inf];
            app.RandSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateRandomTab, true);
            app.ButtonGroup = uibuttongroup(app.RandomTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdateRandomTab, true);
            app.RandFullButton = uitogglebutton(app.ButtonGroup);
            app.RandFullButton.Text = 'Fully Random';
            app.RandFullButton.Value = true;
            app.RandEvenButton = uitogglebutton(app.ButtonGroup);
            app.RandEvenButton.Text = 'Present Evenly';
            app.MoveButton_6 = uibutton(app.RandomTab, 'state');
            app.MoveButton_6.ValueChangedFcn = createCallbackFcn(app, @ToggleMoveMode, true);
            app.MoveButton_6.Text = 'Move';
            app.DeleteButton_6 = uibutton(app.RandomTab, 'push');
            app.DeleteButton_6.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton_6.Text = 'Delete';
            app.CodeTab = uitab(app.TabGroup);
            app.CodeTab.Title = 'Code';
            app.TyperawMATLABcodeTextAreaLabel = uilabel(app.CodeTab);
            app.TyperawMATLABcodeTextAreaLabel.Text = 'Type raw MATLAB code:';
            app.CodeText = uitextarea(app.CodeTab);
            app.CodeText.ValueChangedFcn = createCallbackFcn(app, @UpdateCodeTab, true);
            app.MoveButton_7 = uibutton(app.CodeTab, 'state');
            app.MoveButton_7.ValueChangedFcn = createCallbackFcn(app, @ToggleMoveMode, true);
            app.MoveButton_7.Text = 'Move';
            app.DeleteButton_7 = uibutton(app.CodeTab, 'push');
            app.DeleteButton_7.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton_7.Text = 'Delete';
            app.NoneTab = uitab(app.TabGroup);
            app.NoneTab.Title = ' ';
            app.AddActionNextButton = uibutton(app.UIFigure, 'state');
            app.AddActionNextButton.ValueChangedFcn = createCallbackFcn(app, @AddActionNext, true);
            app.AddActionNextButton.Text = 'Add Action Next';
            app.AddActionWithinButton = uibutton(app.UIFigure, 'state');
            app.AddActionWithinButton.ValueChangedFcn = createCallbackFcn(app, @AddActionWithin, true);
            app.AddActionWithinButton.Enable = 'off';
            app.AddActionWithinButton.Text = 'Add Action Within';
            app.RoutinePanel = uipanel(app.UIFigure);
            app.RoutineTree = uitree(app.RoutinePanel);
            app.RoutineTree.SelectionChangedFcn = createCallbackFcn(app, @RoutineTreeSelectionChanged, true);
            app.RTBlank = uitreenode(app.RoutineTree);
            app.RTBlank.NodeData = 'N';
            app.RTBlank.Text = '';
            app.AddRoutineWithinButton = uibutton(app.RoutinePanel, 'push');
            app.AddRoutineWithinButton.ButtonPushedFcn = createCallbackFcn(app, @AddRoutineWithinButtonPushed, true);
            app.AddRoutineWithinButton.Enable = 'off';
            app.AddRoutineWithinButton.Text = 'Add Within >>';
            app.AddRoutineNextButton = uibutton(app.RoutinePanel, 'push');
            app.AddRoutineNextButton.ButtonPushedFcn = createCallbackFcn(app, @AddRoutineNext, true);
            app.AddRoutineNextButton.Enable = 'off';
            app.AddRoutineNextButton.Text = 'Add Next >>';
            app.SavedRoutinesLabel = uilabel(app.RoutinePanel);
            app.SavedRoutinesLabel.Text = 'Saved Routines';
            app.RoutineDetailsTab = uipanel(app.UIFigure);
            app.RoutineDetailsTab.Visible = 'off';
            app.RoutineFileEdit = uibutton(app.RoutineDetailsTab, 'push');
            app.RoutineFileEdit.ButtonPushedFcn = createCallbackFcn(app, @RoutineFileEditButtonPushed, true);
            app.RoutineFileEdit.Text = 'Edit Routine';
            app.RoutineFileName = uilabel(app.RoutineDetailsTab);
            app.RoutineFileName.Text = 'Routine Name';
            app.RoutineFileDur = uilabel(app.RoutineDetailsTab);
            app.RoutineFileDur.Text = 'Duration';
            app.RoutineFileElements = uilabel(app.RoutineDetailsTab);
            app.RoutineFileElements.Text = '# Elements';
            app.RoutineFileDesign = uitable(app.RoutineDetailsTab);
            app.RoutineFileDesign.ColumnName = {'Factor'; 'Level'};
            app.RoutineFileDesign.RowName = {};
            app.RoutineFileDesignLabel = uilabel(app.RoutineDetailsTab);
            app.RoutineFileDesignLabel.Text = 'Design';
            app.xButton = uibutton(app.RoutineDetailsTab, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @HideRoutineDetailsTab, true);
            app.xButton.Text = 'x';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Procedure

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end