classdef Routine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RoutineName                 matlab.ui.control.EditField
        ElementsInRoutineLabel      matlab.ui.control.Label
        RoutineElements             matlab.ui.container.Tree
        REBlank                     matlab.ui.container.TreeNode
        UIAxes                      matlab.ui.control.UIAxes
        RemoveFromRoutineButton     matlab.ui.control.Button
        StartatSlider               matlab.ui.control.Slider
        RoutineDurationLabel        matlab.ui.control.Label
        RoutineDurationSpinner      matlab.ui.control.Spinner
        StartElementAtSpinnerLabel  matlab.ui.control.Label
        StartElementAtSpinner       matlab.ui.control.Spinner
        FactorsButton               matlab.ui.control.Button
        FactorTable                 matlab.ui.control.Table
        SaveButton                  matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        ElementPanel                matlab.ui.container.Panel
        SavedElementsLabel          matlab.ui.control.Label
        AllElements                 matlab.ui.container.Tree
        AEBlank                     matlab.ui.container.TreeNode
        AddToRoutineButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        rootdir % Description
    end
    
    methods (Access = public)
        
        function OrderEmts(app)
            nodes = [app.RoutineElements.Children.NodeData];
            if ~isempty(nodes)
                ln = [nodes.Line];
                app.UIAxes.YLim(2) = length(ln) + 1;
                for l = 1:length(ln)
                    ln(l).YData = [app.UIAxes.YLim(2)-l,app.UIAxes.YLim(2)-l];
                end
            end
        end
    
        function saveobj(app)
            addpath('Routines')
            
            rt = struct(...
                'Name', app.RoutineName.Value, ...
                'Dur', app.RoutineDurationSpinner.Value, ...
                'Factors', {[app.FactorTable.ColumnName, app.FactorTable.Data']}, ...
                'Elements', [] ...
                );
            for e = 1:length(app.RoutineElements.Children)-1
                current_element = app.RoutineElements.Children(e);
                el{e} = current_element.NodeData;
                el{e}.Start = current_element.NodeData.Line.XData(1);
            end
            if length(app.RoutineElements.Children)-1 > 0
                rt.Elements = el;
            end
            
            save(['Routines\' app.RoutineName.Value], 'rt');
        end
    
        function loadobj(app, rt)
            app.RoutineName.Value = rt.Name;
            app.RoutineDurationSpinner.Value = rt.Dur;
            RoutineDurationSpinnerValueChanged(app, struct('Value', rt.Dur));
            if ~isempty([rt.Factors{:}])
                app.FactorTable.Data = rt.Factors(:,2)';
                app.FactorTable.ColumnName = rt.Factors(:,1);
                app.FactorTable.Visible = true;
            end
            
            for e = 1:length(rt.Elements)
                el = rt.Elements{e};
                node = uitreenode(app.RoutineElements,  app.REBlank, 'before', ...
                    'Text', el.ElementName, ...
                    'NodeData', el, ...
                    'Tag', 'E' ...
                    );
                if node.NodeData.Dur == Inf
                    dur = 1000000;
                else
                    dur = node.NodeData.Dur;
                end
                layer = app.UIAxes.YLim(2);
                app.UIAxes.YLim(2) = layer + 1;
                node.NodeData.Line = plot(app.UIAxes, ...
                    [el.Start,dur], [layer,layer], ...
                    '-d', 'Color', [0,33/255,71/255], ...
                    'LineWidth', 10); %draw element
                hold(app.UIAxes, 'on');
            end
            OrderEmts(app)
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center');
            app.UIFigure.Visible = 'on';
            
            elementList = dir('Elements/*.mat');
            for e = 1:length(elementList)
                uitreenode(app.AllElements,  app.AEBlank, 'before', ...
                    'Text', elementList(e).name, ...
                    'Tag', 'E' ...
                    );
            end
        end

        % Button pushed function: AddToRoutineButton
        function AddToRoutineButtonPushed(app, event)

            load(['Elements\', app.AllElements.SelectedNodes.Text]);
            node = uitreenode(app.RoutineElements,  app.REBlank, 'before', ...
                'Text', app.AllElements.SelectedNodes.Text, ...
                'NodeData', el, ...
                'Tag', 'E' ...
                );
            if node.NodeData.Dur == Inf
                dur = 1000000;
            else
                dur = node.NodeData.Dur;
            end
            layer = app.UIAxes.YLim(2);
            app.UIAxes.YLim(2) = layer + 1;
            node.NodeData.Line = plot(app.UIAxes, ...
                [0,dur], [layer,layer], ...
                '-d', 'Color', [0,33/255,71/255], ...
                'LineWidth', 10); %draw element
            hold(app.UIAxes, 'on');
            
            OrderEmts(app)
            
            app.StartatSlider.Visible = 'off';
            
        end

        % Callback function: StartElementAtSpinner, 
        % StartElementAtSpinner, StartatSlider, StartatSlider
        function StartatSliderValueChanged(app, event)
            node = app.RoutineElements.SelectedNodes;
            dur = node.NodeData.Line.XData(2) - node.NodeData.Line.XData(1);
            node.NodeData.Line.XData = [event.Value,event.Value + dur];
            
            app.StartElementAtSpinner.Value = event.Value;
            app.StartatSlider.Value = event.Value;
        end

        % Selection changed function: RoutineElements
        function RoutineElementsSelectionChanged(app, event)
        if event.SelectedNodes ~= app.REBlank
            app.StartatSlider.Visible = ~isempty(event.SelectedNodes.NodeData);
            app.RemoveFromRoutineButton.Enable = ~isempty(event.SelectedNodes.NodeData);
            app.StartatSlider.Value = event.SelectedNodes.NodeData.Line.XData(1);
            app.StartElementAtSpinner.Value = event.SelectedNodes.NodeData.Line.XData(1);
            
            event.SelectedNodes.NodeData.Line.Color = [0.90,0.00,0.49];
            try
                if event.PreviousSelectedNodes ~= app.REBlank
                    event.PreviousSelectedNodes.NodeData.Line.Color = [0 0.1294 0.2784];
                end
            end
        else
            app.StartatSlider.Visible = false;
            event.PreviousSelectedNodes.NodeData.Line.Color = [0 0.1294 0.2784];
        end
        end

        % Button pushed function: RemoveFromRoutineButton
        function RemoveFromRoutineButtonPushed(app, event)
            if ~isempty(app.RoutineElements.SelectedNodes.NodeData)
                delete(app.RoutineElements.SelectedNodes.NodeData.Line)
                delete(app.RoutineElements.SelectedNodes)
            end
            
            if ~isempty([app.RoutineElements.Children.NodeData])
                OrderEmts(app)
            else
                app.StartatSlider.Visible = 'off';
            end
        end

        % Callback function: RoutineDurationSpinner, 
        % RoutineDurationSpinner
        function RoutineDurationSpinnerValueChanged(app, event)
            app.UIAxes.XLim(2) = event.Value;
            app.StartatSlider.Limits(2) = event.Value;
            app.StartElementAtSpinner.Limits(2) = event.Value;
        end

        % Button pushed function: FactorsButton
        function FactorsButtonPushed(app, event)
            fac = Factors;
            fac.Parent = app;
            fac.loadobj([app.FactorTable.ColumnName, app.FactorTable.Data']);
        end

        % Selection changed function: AllElements
        function AllElementsSelectionChanged(app, event)
            app.StartatSlider.Visible = 'off';
            try
                app.RoutineElements.SelectedNodes.NodeData.Line.Color = [0 0.1294 0.2784];
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveobj(app)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RoutineName = uieditfield(app.UIFigure, 'text');
            app.RoutineName.Value = 'Routine Name';
            app.ElementsInRoutineLabel = uilabel(app.UIFigure);
            app.ElementsInRoutineLabel.Text = 'Elements In Routine';
            app.RoutineElements = uitree(app.UIFigure);
            app.RoutineElements.SelectionChangedFcn = createCallbackFcn(app, @RoutineElementsSelectionChanged, true);
            app.REBlank = uitreenode(app.RoutineElements);
            app.REBlank.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, {'Time (ms)'; ''})
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.YTick = 1;
            app.UIAxes.YTickLabel = '';
            app.RemoveFromRoutineButton = uibutton(app.UIFigure, 'push');
            app.RemoveFromRoutineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFromRoutineButtonPushed, true);
            app.RemoveFromRoutineButton.Text = 'Remove From Routine';
            app.StartatSlider = uislider(app.UIFigure);
            app.StartatSlider.MajorTicks = [];
            app.StartatSlider.ValueChangedFcn = createCallbackFcn(app, @StartatSliderValueChanged, true);
            app.StartatSlider.ValueChangingFcn = createCallbackFcn(app, @StartatSliderValueChanged, true);
            app.StartatSlider.MinorTicks = [];
            app.StartatSlider.Visible = 'off';
            app.RoutineDurationLabel = uilabel(app.UIFigure);
            app.RoutineDurationLabel.Text = 'Routine Duration:  ';
            app.RoutineDurationSpinner = uispinner(app.UIFigure);
            app.RoutineDurationSpinner.Step = 100;
            app.RoutineDurationSpinner.ValueChangingFcn = createCallbackFcn(app, @RoutineDurationSpinnerValueChanged, true);
            app.RoutineDurationSpinner.Limits = [10 Inf];
            app.RoutineDurationSpinner.RoundFractionalValues = 'on';
            app.RoutineDurationSpinner.ValueChangedFcn = createCallbackFcn(app, @RoutineDurationSpinnerValueChanged, true);
            app.RoutineDurationSpinner.Value = 100;
            app.StartElementAtSpinnerLabel = uilabel(app.UIFigure);
            app.StartElementAtSpinnerLabel.Text = 'Start Element At:  ';
            app.StartElementAtSpinner = uispinner(app.UIFigure);
            app.StartElementAtSpinner.Step = 100;
            app.StartElementAtSpinner.ValueChangingFcn = createCallbackFcn(app, @StartatSliderValueChanged, true);
            app.StartElementAtSpinner.Limits = [0 100];
            app.StartElementAtSpinner.RoundFractionalValues = 'on';
            app.StartElementAtSpinner.ValueChangedFcn = createCallbackFcn(app, @StartatSliderValueChanged, true);
            app.FactorsButton = uibutton(app.UIFigure, 'push');
            app.FactorsButton.ButtonPushedFcn = createCallbackFcn(app, @FactorsButtonPushed, true);
            app.FactorsButton.Text = 'Factors...';
            app.FactorTable = uitable(app.UIFigure);
            app.FactorTable.ColumnName = {''};
            app.FactorTable.RowName = {};
            app.FactorTable.ColumnEditable = false;
            app.FactorTable.Enable = 'off';
            app.FactorTable.Visible = 'off';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ElementPanel = uipanel(app.UIFigure);
            app.SavedElementsLabel = uilabel(app.ElementPanel);
            app.SavedElementsLabel.Text = 'Saved Elements';
            app.AllElements = uitree(app.ElementPanel);
            app.AllElements.SelectionChangedFcn = createCallbackFcn(app, @AllElementsSelectionChanged, true);
            app.AEBlank = uitreenode(app.AllElements);
            app.AEBlank.Text = '';
            app.AddToRoutineButton = uibutton(app.ElementPanel, 'push');
            app.AddToRoutineButton.ButtonPushedFcn = createCallbackFcn(app, @AddToRoutineButtonPushed, true);
            app.AddToRoutineButton.Text = 'Add To Routine';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Routine

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end